classdef AbstractProcessGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        pushbutton_cancel  matlab.ui.control.Button
        uipanel_methods    matlab.ui.container.ButtonGroup
        text_methods       matlab.ui.control.Label
        pushbutton_set     matlab.ui.control.Button
        popupmenu_methods  matlab.ui.control.DropDown
        text_processName   matlab.ui.control.Label
        text_copyright     matlab.ui.control.Label
        axes_help          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.DeleteFcn = createCallbackFcn(app, @figure1_DeleteFcn, true);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function abstractProcessGUI_OpeningFcn(app, varargin)
            % --- Executes just before abstractProcessGUI is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            processGUI_OpeningFcn(hObject, eventdata, handles, varargin{:})
            
            % Get current package and process
            userData = get(handles.figure1, 'UserData');
            
            procName = eval([userData.crtProcClassName '.getName()']);
            set(handles.uipanel_methods, 'Title', [procName ' methods']);
            set(handles.text_methods, 'String', ['Choose a ' lower(procName) ' method']);
            
            
            % Get current process constructer, set-up GUIs and mask refinement process
            % constructor
            try
                userData.subProcClassNames = eval([userData.crtProcClassName '.getConcreteClasses(userData.MD)']);
            catch
                warning('Attempted to pass MovieData object to retreive appropropriate concrete classes');
                userData.subProcClassNames = eval([userData.crtProcClassName '.getConcreteClasses()']);
            end
            
            isGraphicalProcess = @(x) Process.isProcess(x) && Process.hasGUI(x);
            validClasses = cellfun(isGraphicalProcess, userData.subProcClassNames);
            userData.subProcClassNames = userData.subProcClassNames(validClasses);
            userData.subProcConstr = cellfun(@(x) str2func(x),userData.subProcClassNames,'Unif',0);
            userData.subProcGUI = cellfun(@(x) eval([x '.GUI']),userData.subProcClassNames,'Unif',0);
            subProcNames = cellfun(@(x) eval([x '.getName']),userData.subProcClassNames,'Unif',0);
            popupMenuProcName = vertcat(subProcNames,{['Choose a ' lower(procName) ' method']});
            
            % Set up input channel list box
            if isempty(userData.crtProc)
                value = numel(userData.subProcClassNames)+1;
                set(handles.pushbutton_set, 'Enable', 'off');
            else
            %     value = find(strcmp(userData.crtProc.getName,subProcNames));
                value = find(cellfun(@isempty, strfind(subProcNames, userData.crtProc.getName))==0,1);
                if isempty(value); value = numel(subProcNames)+1; end
            end
            
            existSubProc = @(proc) any(cellfun(@(x) isa(x,proc),userData.MD.processes_));
            for i=find(cellfun(existSubProc,userData.subProcClassNames'))
              popupMenuProcName{i} = ['<html><b>' popupMenuProcName{i} '</b></html>'];
            end
            
            set(handles.popupmenu_methods, 'String', popupMenuProcName,...
                'Value',value)
            
            % Choose default command line output for abstractProcessGUI
            handles.output = hObject;
            
            % Update user data and GUI data
            set(hObject, 'UserData', userData);
            guidata(hObject, handles);
        end

        % Callback function
        function figure1_DeleteFcn(app, event)
            % --- Executes during object deletion, before destroying properties.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            userData = get(handles.figure1, 'UserData');
            if ~isempty(userData) %%%% check and see why
            delete(userData.helpFig(ishandle(userData.helpFig)));
            end
            set(handles.figure1, 'UserData', userData);
            guidata(hObject,handles);
        end

        % Value changed function: popupmenu_methods
        function popupmenu_methods_Callback(app, event)
            % --- Executes on selection change in popupmenu_methods.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_methods (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_methods contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_methods
            content = get(hObject, 'string');
            if get(hObject, 'Value') == length(content)
                set(handles.pushbutton_set, 'Enable', 'off')
            else
                set(handles.pushbutton_set, 'Enable', 'on')
            end
        end

        % Button pushed function: pushbutton_cancel
        function pushbutton_cancel_Callback(app, event)
            % --- Executes on button press in pushbutton_cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Delete figure
            delete(handles.figure1);
        end

        % Button pushed function: pushbutton_set
        function pushbutton_set_Callback(app, event)
            % --- Executes on button press in pushbutton_set.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            userData = get(handles.figure1, 'UserData');
            segProcID = get(handles.popupmenu_methods, 'Value');
            subProcGUI = userData.subProcGUI{segProcID};
            subProcGUI('mainFig',userData.mainFig,userData.procID,...
                'procConstr',userData.subProcConstr{segProcID},...
                'procClassName',userData.subProcClassNames{segProcID});
            delete(handles.figure1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Setting - Segmentation';
            app.figure1.Tag = 'figure1';
            app.axes_help = uiaxes(app.figure1);
            app.axes_help.Tag = 'axes_help';
            app.axes_help.Visible = 'off';
            app.text_copyright = uilabel(app.figure1);
            app.text_copyright.Tag = 'text_copyright';
            app.text_copyright.Text = 'copyright info';
            app.text_processName = uilabel(app.figure1);
            app.text_processName.Tag = 'text_processName';
            app.text_processName.Text = 'Step xx : process';
            app.uipanel_methods = uibuttongroup(app.figure1);
            app.uipanel_methods.Title = 'Detection methods';
            app.uipanel_methods.Tag = 'uipanel_methods';
            app.popupmenu_methods = uidropdown(app.uipanel_methods);
            app.popupmenu_methods.Items = {'Detection methods', 'Choose ...'};
            app.popupmenu_methods.ValueChangedFcn = createCallbackFcn(app, @popupmenu_methods_Callback, true);
            app.popupmenu_methods.Tag = 'popupmenu_methods';
            app.popupmenu_methods.Value = 'Detection methods';
            app.pushbutton_set = uibutton(app.uipanel_methods, 'push');
            app.pushbutton_set.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_set_Callback, true);
            app.pushbutton_set.Tag = 'pushbutton_set';
            app.pushbutton_set.Text = 'Setting ...';
            app.text_methods = uilabel(app.uipanel_methods);
            app.text_methods.Tag = 'text_methods';
            app.text_methods.Text = 'Please choose a detection method';
            app.pushbutton_cancel = uibutton(app.figure1, 'push');
            app.pushbutton_cancel.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_cancel_Callback, true);
            app.pushbutton_cancel.Tag = 'pushbutton_cancel';
            app.pushbutton_cancel.Text = 'Cancel';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AbstractProcessGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)abstractProcessGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end