classdef ExternalSegmentationProcessGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        checkbox_applytoall        matlab.ui.control.CheckBox
        uipanel_2                  matlab.ui.container.ButtonGroup
        listbox_externalmaskpaths  matlab.ui.control.ListBox
        pushbutton_down            matlab.ui.control.Button
        pushbutton_up              matlab.ui.control.Button
        text_mask                  matlab.ui.control.Label
        pushbutton_add             matlab.ui.control.Button
        pushbutton_deletechannel   matlab.ui.control.Button
        pushbutton_done            matlab.ui.control.Button
        pushbutton_cancel          matlab.ui.control.Button
        text_processName           matlab.ui.control.Label
        text_copyright             matlab.ui.control.Label
        uipanel_1                  matlab.ui.container.ButtonGroup
        listbox_selectedChannels   matlab.ui.control.ListBox
        pushbutton_delete          matlab.ui.control.Button
        pushbutton_select          matlab.ui.control.Button
        text_input1                matlab.ui.control.Label
        text_input2                matlab.ui.control.Label
        checkbox_all               matlab.ui.control.CheckBox
        listbox_availableChannels  matlab.ui.control.ListBox
        axes_help                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.DeleteFcn = createCallbackFcn(app, @figure1_DeleteFcn, true);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function externalSegmentationProcessGUI_OpeningFcn(app, varargin)
            % --- Executes just before externalSegmentationProcessGUI is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            processGUI_OpeningFcn(hObject, eventdata, handles, varargin{:},'initChannel',1);
            
            % Parameter setup
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            funParams = userData.crtProc.funParams_;
            
            if ~all(cellfun(@isempty, funParams.InputData));
                set(handles.listbox_externalmaskpaths, 'String', ...
                    funParams.InputData(funParams.ChannelIndex));
            end
            
            set(handles.listbox_externalmaskpaths, 'Value',1);
            
            % Choose default command line output for externalSegmentationProcessGUI
            handles.output = hObject;
            
            % Update user data and GUI data
            set(handles.figure1, 'UserData', userData);
            uicontrol(handles.pushbutton_done)
            guidata(hObject, handles);
        end

        % Callback function
        function figure1_DeleteFcn(app, event)
            % --- Executes during object deletion, before destroying properties.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            
            if isfield(userData, 'helpFig') && ishandle(userData.helpFig)
               delete(userData.helpFig)
            end
            
            set(handles.figure1, 'UserData', userData);
            guidata(hObject,handles);
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)
            % --- Executes on key press with focus on figure1 and none of its controls.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  structure with the following fields (see FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            if strcmp(eventdata.Key, 'return')
                pushbutton_done_Callback(app, []);
            end
        end

        % Button pushed function: pushbutton_add
        function pushbutton_add_Callback(app, event)
            % --- Executes on button press in pushbutton_add.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Call back of 'add' button
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            
            set(handles.listbox_externalmaskpaths, 'Value',1)
            
            path = uigetdir(userData.MD.getPath(), 'Add Channels ...');
            if path == 0
                return;
            end
            % Input validation function ...
            % Get current list
            contents = get(handles.listbox_externalmaskpaths,'String');
            
            % Add current formula to the listbox
            contents{end+1} = path;
            set(handles.listbox_externalmaskpaths,'string',contents);
            
            % Set user directory
            sepDir = regexp(path, filesep, 'split');
            dir = sepDir{1};
            for i = 2: length(sepDir)-1
                dir = [dir filesep sepDir{i}];
            end
            userData.userDir = dir;
            
            set(handles.figure1, 'Userdata', userData)
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_cancel
        function pushbutton_cancel_Callback(app, event)
            % --- Executes on button press in pushbutton_cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            delete(handles.figure1);
        end

        % Button pushed function: pushbutton_deletechannel
        function pushbutton_deletechannel_Callback(app, event)
            % --- Executes on button press in pushbutton_deletechannel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Call back function of 'delete' button
            contents = get(handles.listbox_externalmaskpaths,'String');
            % Return if list is empty
            if isempty(contents)
                return;
            end
            num = get(handles.listbox_externalmaskpaths,'Value');
            
            % Delete selected item
            contents(num) = [ ];
            
            % Refresh listbox
            set(handles.listbox_externalmaskpaths,'String',contents);
            % Point 'Value' to the second last item in the list once the
            % last item has been deleted
            if (num>length(contents) && num>1)
                set(handles.listbox_externalmaskpaths,'Value',length(contents));
            end
            
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_done
        function pushbutton_done_Callback(app, event)
            % --- Executes on button press in pushbutton_done.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            % Call back function of 'Apply' button
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            
            channelProps = get (handles.listbox_selectedChannels, {'Userdata','String'});
            channelIndex=channelProps{1};
            channelString=channelProps{2};
            maskPaths = get(handles.listbox_externalmaskpaths, 'String');
            
            % Check user input
            if isempty(channelString)
                errordlg('Please select at least one input channel from ''Available Channels''.','Setting Error','modal')
                return;
            end
            
            if numel(channelString) ~= numel(maskPaths)
                errordlg('Please provide the same number of mask paths as input channels.','Setting Error','modal')
                return;
            end
            
            oldFunParams = userData.crtProc.funParams_;
            % Apply new channel index and new mask input for sanity check
            funParams.ChannelIndex = channelIndex;
            funParams.InputData = cell(numel(userData.MD.channels_), 1);
            funParams.InputData(channelIndex) = maskPaths;
            
            parseProcessParams(userData.crtProc, funParams);
            
            %  Process Sanity check ( only check underlying data )
            try
                userData.crtProc.sanityCheck();
            catch ME
                errordlg(ME.message,'Setting Error','modal');
                userData.crtProc.setParameters(oldFunParams);
                return;
            end
            
            % Set parameters
            processGUI_ApplyFcn(hObject, eventdata, handles,funParams);
        end

        % Button pushed function: pushbutton_down
        function pushbutton_down_Callback(app, event)
            % --- Executes on button press in pushbutton_down.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Call back of 'Down' button
            
            id = get(handles.listbox_externalmaskpaths,'Value');
            contents = get(handles.listbox_externalmaskpaths,'String');
            
            % Return if list is empty
            if isempty(contents) || isempty(id) || id == length(contents)
                return;
            end
            
            temp = contents{id};
            contents{id} = contents{id+1};
            contents{id+1} = temp;
            
            set(handles.listbox_externalmaskpaths, 'string', contents);
            set(handles.listbox_externalmaskpaths, 'value', id+1);
        end

        % Button pushed function: pushbutton_up
        function pushbutton_up_Callback(app, event)
            % --- Executes on button press in pushbutton_up.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % call back of 'Up' button
            
            id = get(handles.listbox_externalmaskpaths,'Value');
            contents = get(handles.listbox_externalmaskpaths,'String');
            
            % Return if list is empty
            if isempty(contents) || isempty(id) || id == 1
                return;
            end
            
            temp = contents{id};
            contents{id} = contents{id-1};
            contents{id-1} = temp;
            
            set(handles.listbox_externalmaskpaths, 'string', contents);
            set(handles.listbox_externalmaskpaths, 'value', id-1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Setting - Shade Correction';
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.axes_help = uiaxes(app.figure1);
            app.axes_help.Tag = 'axes_help';
            app.axes_help.Visible = 'off';
            app.uipanel_1 = uibuttongroup(app.figure1);
            app.uipanel_1.Title = 'Input Channels';
            app.uipanel_1.Tag = 'uipanel_1';
            app.listbox_availableChannels = uilistbox(app.uipanel_1);
            app.listbox_availableChannels.Items = {};
            app.listbox_availableChannels.Multiselect = 'on';
            app.listbox_availableChannels.Tag = 'listbox_availableChannels';
            app.listbox_availableChannels.Value = {};
            app.checkbox_all = uicheckbox(app.uipanel_1);
            app.checkbox_all.Tag = 'checkbox_all';
            app.checkbox_all.Text = ' Select all channels';
            app.text_input2 = uilabel(app.uipanel_1);
            app.text_input2.Tag = 'text_input2';
            app.text_input2.Text = 'Selected Channels';
            app.text_input1 = uilabel(app.uipanel_1);
            app.text_input1.Tag = 'text_input1';
            app.text_input1.Text = 'Available Input Channels';
            app.pushbutton_select = uibutton(app.uipanel_1, 'push');
            app.pushbutton_select.Tag = 'pushbutton_select';
            app.pushbutton_select.Text = 'Select ->';
            app.pushbutton_delete = uibutton(app.uipanel_1, 'push');
            app.pushbutton_delete.Tag = 'pushbutton_delete';
            app.pushbutton_delete.Text = 'Delete';
            app.listbox_selectedChannels = uilistbox(app.uipanel_1);
            app.listbox_selectedChannels.Items = {};
            app.listbox_selectedChannels.Tag = 'listbox_selectedChannels';
            app.listbox_selectedChannels.Value = {};
            app.text_copyright = uilabel(app.figure1);
            app.text_copyright.Tag = 'text_copyright';
            app.text_copyright.Text = 'copyright info';
            app.text_processName = uilabel(app.figure1);
            app.text_processName.Tag = 'text_processName';
            app.text_processName.Text = 'Step xxx:  External segmentation import';
            app.pushbutton_cancel = uibutton(app.figure1, 'push');
            app.pushbutton_cancel.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_cancel_Callback, true);
            app.pushbutton_cancel.Tag = 'pushbutton_cancel';
            app.pushbutton_cancel.Text = 'Cancel';
            app.pushbutton_done = uibutton(app.figure1, 'push');
            app.pushbutton_done.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_done_Callback, true);
            app.pushbutton_done.Tag = 'pushbutton_done';
            app.pushbutton_done.Text = 'Apply';
            app.uipanel_2 = uibuttongroup(app.figure1);
            app.uipanel_2.Title = 'External mask paths';
            app.uipanel_2.Tag = 'uipanel_2';
            app.pushbutton_deletechannel = uibutton(app.uipanel_2, 'push');
            app.pushbutton_deletechannel.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_deletechannel_Callback, true);
            app.pushbutton_deletechannel.Tag = 'pushbutton_deletechannel';
            app.pushbutton_deletechannel.Text = 'Delete';
            app.pushbutton_add = uibutton(app.uipanel_2, 'push');
            app.pushbutton_add.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_add_Callback, true);
            app.pushbutton_add.Tag = 'pushbutton_add';
            app.pushbutton_add.Text = 'Add mask path';
            app.text_mask = uilabel(app.uipanel_2);
            app.text_mask.Tag = 'text_mask';
            app.text_mask.Text = {'Please provide the paths which contain the masks (in the same number and order as input channels.):'; ''};
            app.pushbutton_up = uibutton(app.uipanel_2, 'push');
            app.pushbutton_up.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_up_Callback, true);
            app.pushbutton_up.Tag = 'pushbutton_up';
            app.pushbutton_up.Text = 'Up';
            app.pushbutton_down = uibutton(app.uipanel_2, 'push');
            app.pushbutton_down.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_down_Callback, true);
            app.pushbutton_down.Tag = 'pushbutton_down';
            app.pushbutton_down.Text = 'Down';
            app.listbox_externalmaskpaths = uilistbox(app.uipanel_2);
            app.listbox_externalmaskpaths.Items = {};
            app.listbox_externalmaskpaths.Tag = 'listbox_externalmaskpaths';
            app.listbox_externalmaskpaths.Value = {};
            app.checkbox_applytoall = uicheckbox(app.figure1);
            app.checkbox_applytoall.Tag = 'checkbox_applytoall';
            app.checkbox_applytoall.Text = 'Apply settings to all movies';
            app.checkbox_applytoall.Value = true;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExternalSegmentationProcessGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)externalSegmentationProcessGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end