classdef MaskRefinementProcessGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        checkbox_applytoall         matlab.ui.control.CheckBox
        pushbutton_done             matlab.ui.control.Button
        pushbutton_cancel           matlab.ui.control.Button
        text_processName            matlab.ui.control.Label
        text_copyright              matlab.ui.control.Label
        uipanel_1                   matlab.ui.container.ButtonGroup
        listbox_selectedChannels    matlab.ui.control.ListBox
        pushbutton_delete           matlab.ui.control.Button
        pushbutton_select           matlab.ui.control.Button
        text_channels1              matlab.ui.control.Label
        text_channels2              matlab.ui.control.Label
        checkbox_all                matlab.ui.control.CheckBox
        listbox_availableChannels   matlab.ui.control.ListBox
        uipanel_2                   matlab.ui.container.ButtonGroup
        popupmenu_SegProcessIndex   matlab.ui.control.DropDown
        text_SegProcessIndex        matlab.ui.control.Label
        uipanel_cleanup             matlab.ui.container.Panel
        checkbox_FillBoundaryHoles  matlab.ui.control.CheckBox
        text18                      matlab.ui.control.Label
        text17                      matlab.ui.control.Label
        checkbox_fillholes          matlab.ui.control.CheckBox
        edit_3                      matlab.ui.control.EditField
        text_para3                  matlab.ui.control.Label
        edit_2                      matlab.ui.control.EditField
        text_para2                  matlab.ui.control.Label
        text_para1                  matlab.ui.control.Label
        edit_1                      matlab.ui.control.EditField
        uipanel_edge                matlab.ui.container.Panel
        text16                      matlab.ui.control.Label
        text15                      matlab.ui.control.Label
        text14                      matlab.ui.control.Label
        edit_6                      matlab.ui.control.EditField
        text_para6                  matlab.ui.control.Label
        edit_5                      matlab.ui.control.EditField
        text_para5                  matlab.ui.control.Label
        text_para4                  matlab.ui.control.Label
        edit_4                      matlab.ui.control.EditField
        checkbox_edge               matlab.ui.control.CheckBox
        checkbox_cleanup            matlab.ui.control.CheckBox
        axes_help                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.DeleteFcn = createCallbackFcn(app, @figure1_DeleteFcn, true);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function maskRefinementProcessGUI_OpeningFcn(app, varargin)
            % --- Executes just before maskRefinementProcessGUI is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            processGUI_OpeningFcn(hObject, eventdata, handles, varargin{:},'initChannel',1);
            
            % Parameters setup
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            funParams = userData.crtProc.funParams_;
            
            % set GUI with Parameters
            segProc =  cellfun(@(x) isa(x,'MaskProcess'),userData.MD.processes_);
            segProcID=find(segProc);
            segProcNames = cellfun(@(x) x.getName(),userData.MD.processes_(segProc),'Unif',false);
            segProcString = vertcat('Choose later',segProcNames(:));
            segProcData=horzcat({[]},num2cell(segProcID));
            segProcValue = find(cellfun(@(x) isequal(x,funParams.SegProcessIndex),segProcData));
            if isempty(segProcValue), segProcValue = 1; end
            set(handles.popupmenu_SegProcessIndex,'String',segProcString,...
                'UserData',segProcData,'Value',segProcValue);
            
            % Update channels listboxes depending on the selected process
            popupmenu_SegProcessIndex_Callback(app, [])
            
            if funParams.MaskCleanUp
                if ~funParams.FillHoles
                    set(handles.checkbox_fillholes, 'Value', 0)
                else
                    set(handles.checkbox_fillholes, 'Value', 1)
                end
                if ~funParams.FillBoundaryHoles
                    set(handles.checkbox_FillBoundaryHoles, 'Value', 0)
                else
                    set(handles.checkbox_FillBoundaryHoles, 'Value', 1)
                end
                set(handles.edit_1, 'String',num2str(funParams.MinimumSize))
                set(handles.edit_2, 'String',num2str(funParams.ClosureRadius))
                set(handles.edit_3, 'String',num2str(funParams.ObjectNumber))
            else
                set([handles.checkbox_cleanup handles.checkbox_fillholes], 'Value', 0);
                set(get(handles.uipanel_cleanup,'Children'),'Enable','off');
            end
            
            if funParams.EdgeRefinement
                set(handles.checkbox_edge, 'Value', 1)
                set(handles.text_para4, 'Enable', 'on');
                set(handles.text_para5, 'Enable', 'on');
                set(handles.text_para6, 'Enable', 'on');
                set(handles.edit_4, 'Enable', 'on', 'String',num2str(funParams.MaxEdgeAdjust));
                set(handles.edit_5, 'Enable', 'on', 'String',num2str(funParams.MaxEdgeGap));
                set(handles.edit_6, 'Enable', 'on', 'String',num2str(funParams.PreEdgeGrow));
            
            end
            
            % Update user data and GUI data
            handles.output = hObject;
            set(handles.figure1, 'UserData', userData);
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_cleanup
        function checkbox_cleanup_Callback(app, event)
            % --- Executes on button press in checkbox_cleanup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_auto
            
            if get(hObject, 'Value')
                set(get(handles.uipanel_cleanup,'Children'),'Enable','on');
            else
                set(get(handles.uipanel_cleanup,'Children'),'Enable','off');
            end
        end

        % Value changed function: checkbox_edge
        function checkbox_edge_Callback(app, event)
            % --- Executes on button press in checkbox_edge.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_auto
            
            if get(hObject, 'Value')
                set(get(handles.uipanel_edge,'Children'),'Enable','on');
            else
                set(get(handles.uipanel_edge,'Children'),'Enable','off');
            end
        end

        % Callback function
        function figure1_DeleteFcn(app, event)
            % --- Executes during object deletion, before destroying properties.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            
            if isfield(userData, 'helpFig') && ishandle(userData.helpFig)
               delete(userData.helpFig)
            end
            
            set(handles.figure1, 'UserData', userData);
            guidata(hObject,handles);
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)
            % --- Executes on key press with focus on figure1 and none of its controls.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  structure with the following fields (see FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            if strcmp(eventdata.Key, 'return')
                pushbutton_done_Callback(handles.pushbutton_done, [], handles);
            end
        end

        % Value changed function: popupmenu_SegProcessIndex
        function popupmenu_SegProcessIndex_Callback(app, event)
            % --- Executes on selection change in popupmenu_SegProcessIndex.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            % Retrieve selected process ID
            props= get(handles.popupmenu_SegProcessIndex,{'UserData','Value'});
            procID = props{1}{props{2}};
            
            % Read process and check available channels
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            if isempty(procID)
                allChannelIndex=1:numel(userData.MD.channels_);
            else
                allChannelIndex = find(userData.MD.processes_{procID}.checkChannelOutput);
            end
            
            % Set up available channels listbox
            if ~isempty(allChannelIndex)
                if isempty(procID)
                    channelString = userData.MD.getChannelPaths(allChannelIndex);
                else
                    channelString = userData.MD.processes_{procID}.outFilePaths_(1,allChannelIndex);
                end
            else
                channelString = {};
            end
            set(handles.listbox_availableChannels,'String',channelString,'UserData',allChannelIndex);
            
            % Set up selected channels listbox
            channelIndex = get(handles.listbox_selectedChannels, 'UserData');
            channelIndex = intersect(channelIndex,allChannelIndex);
            if ~isempty(channelIndex)
                if isempty(procID)
                    channelString = userData.MD.getChannelPaths(channelIndex);
                else
                    channelString = userData.MD.processes_{procID}.outFilePaths_(1,channelIndex);
                end
            else
                channelString = {};
            end
            set(handles.listbox_selectedChannels,'String',channelString,'UserData',channelIndex);
        end

        % Button pushed function: pushbutton_cancel
        function pushbutton_cancel_Callback(app, event)
            % --- Executes on button press in pushbutton_cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_cancel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            delete(handles.figure1);
        end

        % Button pushed function: pushbutton_done
        function pushbutton_done_Callback(app, event)
            % --- Executes on button press in pushbutton_done.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Call back function of 'Apply' button
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            
            % -------- Check user input --------
            
            if isempty(get(handles.listbox_selectedChannels, 'String'))
               errordlg('Please select at least one input channel from ''Available Channels''.','Setting Error','modal')
                return;
            end
            
            if get(handles.checkbox_cleanup, 'value')
                if isnan(str2double(get(handles.edit_1, 'String'))) ...
                        || str2double(get(handles.edit_1, 'String')) < 0
                    errordlg('Please provide a valid input for ''Minimus Size''.','Setting Error','modal');
                    return;
                end
                if isnan(str2double(get(handles.edit_2, 'String'))) ...
                        || str2double(get(handles.edit_2, 'String')) < 0
                    errordlg('Please provide a valid input for ''Closure Radius''.','Setting Error','modal');
                    return;
                end
                if isnan(str2double(get(handles.edit_3, 'String'))) ...
                        || str2double(get(handles.edit_3, 'String')) < 0
                    errordlg('Please provide a valid input for ''Object Number''.','Setting Error','modal');
                    return;
                end
            end
            
            if get(handles.checkbox_edge, 'value')
                if isnan(str2double(get(handles.edit_4, 'String'))) ...
                        || str2double(get(handles.edit_4, 'String')) < 0
                    errordlg('Please provide a valid input for ''Maximum Adjust Distance''.','Setting Error','modal');
                    return;
                end
                if isnan(str2double(get(handles.edit_5, 'String'))) ...
                        || str2double(get(handles.edit_5, 'String')) < 0
                    errordlg('Please provide a valid input for ''Maximum Edge Gap''.','Setting Error','modal');
                    return;
                end
                if isnan(str2double(get(handles.edit_6, 'String'))) ...
                        || str2double(get(handles.edit_6, 'String')) < 0
                    errordlg('Please provide a valid input for ''Radius of Growth''.','Setting Error','modal');
                    return;
                end
            end
            
            if ~get(handles.checkbox_cleanup, 'value') && ~get(handles.checkbox_edge, 'value')
                errordlg('Please select at least one option for mask refinement processing.')
                return;
            end
            
            % -------- Process Sanity check --------
            % ( only check underlying data )
            
            try
                userData.crtProc.sanityCheck;
            catch ME
            
                errordlg([ME.message 'Please double check your data'],...
                            'Setting Error','modal');
                return;
            end
            
            % Retrieve GUI-defined parameters
            channelIndex = get (handles.listbox_selectedChannels, 'Userdata');
            funParams.ChannelIndex = channelIndex;
            
            if get(handles.checkbox_cleanup, 'Value')
                funParams.MaskCleanUp = true;
                funParams.MinimumSize = str2double(get(handles.edit_1, 'String'));
                funParams.ClosureRadius = str2double(get(handles.edit_2, 'String'));
                funParams.ObjectNumber = str2double(get(handles.edit_3, 'String'));
                if get(handles.checkbox_fillholes, 'Value')
                    funParams.FillHoles = true;
                else
                    funParams.FillHoles = false;
                end
                if get(handles.checkbox_FillBoundaryHoles, 'Value')
                    funParams.FillBoundaryHoles = true;
                else
                    funParams.FillBoundaryHoles = false;
                end
            else
                funParams.MaskCleanUp = false;
            end
            
            if get(handles.checkbox_edge, 'Value')
                funParams.EdgeRefinement = true;
                funParams.MaxEdgeAdjust = str2double(get(handles.edit_4, 'String'));
                funParams.MaxEdgeGap = str2double(get(handles.edit_5, 'String'));
                funParams.PreEdgeGrow = str2double(get(handles.edit_6, 'String'));
            else
                funParams.EdgeRefinement = false;
            end
            
            % Retrieve mask process index and class (for propagation)
            props=get(handles.popupmenu_SegProcessIndex,{'UserData','Value'});
            funParams.SegProcessIndex = props{1}{props{2}};
            if ~isempty(funParams.SegProcessIndex)
                segProcessClass=class(userData.MD.processes_{funParams.SegProcessIndex});
            else
                segProcessClass = '';
            end
            
            % Set parameters and update main window
            setMaskProcess = @(x) parseProcessParams(x, struct('SegProcessIndex',...
                x.owner_.getProcessIndex(segProcessClass,1,false)));
            processGUI_ApplyFcn(hObject, eventdata, handles,funParams,'settingFcn',{setMaskProcess});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Setting - Mask Refinement';
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.axes_help = uiaxes(app.figure1);
            app.axes_help.Tag = 'axes_help';
            app.axes_help.Visible = 'off';
            app.uipanel_2 = uibuttongroup(app.figure1);
            app.uipanel_2.Title = 'Parameters';
            app.uipanel_2.Tag = 'uipanel_2';
            app.checkbox_cleanup = uicheckbox(app.uipanel_2);
            app.checkbox_cleanup.ValueChangedFcn = createCallbackFcn(app, @checkbox_cleanup_Callback, true);
            app.checkbox_cleanup.Tag = 'checkbox_cleanup';
            app.checkbox_cleanup.Text = ' Mask Clean-up';
            app.checkbox_cleanup.Value = true;
            app.checkbox_edge = uicheckbox(app.uipanel_2);
            app.checkbox_edge.ValueChangedFcn = createCallbackFcn(app, @checkbox_edge_Callback, true);
            app.checkbox_edge.Tag = 'checkbox_edge';
            app.checkbox_edge.Text = ' Mask Edge Refinement';
            app.uipanel_edge = uipanel(app.uipanel_2);
            app.uipanel_edge.Tag = 'uipanel_edge';
            app.edit_4 = uieditfield(app.uipanel_edge, 'text');
            app.edit_4.Tag = 'edit_4';
            app.edit_4.Enable = 'off';
            app.text_para4 = uilabel(app.uipanel_edge);
            app.text_para4.Tag = 'text_para4';
            app.text_para4.Enable = 'off';
            app.text_para4.Text = 'Maximum Adjust Distance:';
            app.text_para5 = uilabel(app.uipanel_edge);
            app.text_para5.Tag = 'text_para5';
            app.text_para5.Enable = 'off';
            app.text_para5.Text = 'Maximum Edge Gap:';
            app.edit_5 = uieditfield(app.uipanel_edge, 'text');
            app.edit_5.Tag = 'edit_5';
            app.edit_5.Enable = 'off';
            app.text_para6 = uilabel(app.uipanel_edge);
            app.text_para6.Tag = 'text_para6';
            app.text_para6.Enable = 'off';
            app.text_para6.Text = 'Radius of Growth:';
            app.edit_6 = uieditfield(app.uipanel_edge, 'text');
            app.edit_6.Tag = 'edit_6';
            app.edit_6.Enable = 'off';
            app.text14 = uilabel(app.uipanel_edge);
            app.text14.Tag = 'text14';
            app.text14.Text = 'pixels';
            app.text15 = uilabel(app.uipanel_edge);
            app.text15.Tag = 'text15';
            app.text15.Text = 'pixels';
            app.text16 = uilabel(app.uipanel_edge);
            app.text16.Tag = 'text16';
            app.text16.Text = 'pixels';
            app.uipanel_cleanup = uipanel(app.uipanel_2);
            app.uipanel_cleanup.Tag = 'uipanel_cleanup';
            app.edit_1 = uieditfield(app.uipanel_cleanup, 'text');
            app.edit_1.Tag = 'edit_1';
            app.text_para1 = uilabel(app.uipanel_cleanup);
            app.text_para1.Tag = 'text_para1';
            app.text_para1.Text = 'Minimum Size:';
            app.text_para2 = uilabel(app.uipanel_cleanup);
            app.text_para2.Tag = 'text_para2';
            app.text_para2.Text = 'Closure Radius:';
            app.edit_2 = uieditfield(app.uipanel_cleanup, 'text');
            app.edit_2.Tag = 'edit_2';
            app.text_para3 = uilabel(app.uipanel_cleanup);
            app.text_para3.Tag = 'text_para3';
            app.text_para3.Text = 'Object Number:';
            app.edit_3 = uieditfield(app.uipanel_cleanup, 'text');
            app.edit_3.Tag = 'edit_3';
            app.checkbox_fillholes = uicheckbox(app.uipanel_cleanup);
            app.checkbox_fillholes.Tag = 'checkbox_fillholes';
            app.checkbox_fillholes.Text = ' Fill Holes';
            app.checkbox_fillholes.Value = true;
            app.text17 = uilabel(app.uipanel_cleanup);
            app.text17.Tag = 'text17';
            app.text17.Text = 'pixels';
            app.text18 = uilabel(app.uipanel_cleanup);
            app.text18.Tag = 'text18';
            app.text18.Text = 'pixels';
            app.checkbox_FillBoundaryHoles = uicheckbox(app.uipanel_cleanup);
            app.checkbox_FillBoundaryHoles.Tag = 'checkbox_FillBoundaryHoles';
            app.checkbox_FillBoundaryHoles.Text = 'Fill Boundary Holes';
            app.text_SegProcessIndex = uilabel(app.uipanel_2);
            app.text_SegProcessIndex.Tag = 'text_SegProcessIndex';
            app.text_SegProcessIndex.Text = 'Mask process';
            app.popupmenu_SegProcessIndex = uidropdown(app.uipanel_2);
            app.popupmenu_SegProcessIndex.Items = {};
            app.popupmenu_SegProcessIndex.ValueChangedFcn = createCallbackFcn(app, @popupmenu_SegProcessIndex_Callback, true);
            app.popupmenu_SegProcessIndex.Tag = 'popupmenu_SegProcessIndex';
            app.popupmenu_SegProcessIndex.Value = {};
            app.uipanel_1 = uibuttongroup(app.figure1);
            app.uipanel_1.Title = 'Input Channels';
            app.uipanel_1.Tag = 'uipanel_1';
            app.listbox_availableChannels = uilistbox(app.uipanel_1);
            app.listbox_availableChannels.Items = {};
            app.listbox_availableChannels.Multiselect = 'on';
            app.listbox_availableChannels.Tag = 'listbox_availableChannels';
            app.listbox_availableChannels.Value = {};
            app.checkbox_all = uicheckbox(app.uipanel_1);
            app.checkbox_all.Tag = 'checkbox_all';
            app.checkbox_all.Text = ' Select all channels';
            app.text_channels2 = uilabel(app.uipanel_1);
            app.text_channels2.Tag = 'text_channels2';
            app.text_channels2.Text = 'Selected Channels';
            app.text_channels1 = uilabel(app.uipanel_1);
            app.text_channels1.Tag = 'text_channels1';
            app.text_channels1.Text = 'Available Input Channels';
            app.pushbutton_select = uibutton(app.uipanel_1, 'push');
            app.pushbutton_select.Tag = 'pushbutton_select';
            app.pushbutton_select.Text = 'Select ->';
            app.pushbutton_delete = uibutton(app.uipanel_1, 'push');
            app.pushbutton_delete.Tag = 'pushbutton_delete';
            app.pushbutton_delete.Text = 'Delete';
            app.listbox_selectedChannels = uilistbox(app.uipanel_1);
            app.listbox_selectedChannels.Items = {};
            app.listbox_selectedChannels.Tag = 'listbox_selectedChannels';
            app.listbox_selectedChannels.Value = {};
            app.text_copyright = uilabel(app.figure1);
            app.text_copyright.Tag = 'text_copyright';
            app.text_copyright.Text = 'copyright info';
            app.text_processName = uilabel(app.figure1);
            app.text_processName.Tag = 'text_processName';
            app.text_processName.Text = 'Step x: ';
            app.pushbutton_cancel = uibutton(app.figure1, 'push');
            app.pushbutton_cancel.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_cancel_Callback, true);
            app.pushbutton_cancel.Tag = 'pushbutton_cancel';
            app.pushbutton_cancel.Text = 'Cancel';
            app.pushbutton_done = uibutton(app.figure1, 'push');
            app.pushbutton_done.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_done_Callback, true);
            app.pushbutton_done.Tag = 'pushbutton_done';
            app.pushbutton_done.Text = 'Apply';
            app.checkbox_applytoall = uicheckbox(app.figure1);
            app.checkbox_applytoall.Tag = 'checkbox_applytoall';
            app.checkbox_applytoall.Text = 'Apply settings to all movies';
            app.checkbox_applytoall.Value = true;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaskRefinementProcessGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)maskRefinementProcessGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end