classdef MsaSegmentationProcessGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        uipanel_2                     matlab.ui.container.ButtonGroup
        numVotes_panel                matlab.ui.container.Panel
        text26                        matlab.ui.control.Label
        numVotes_display              matlab.ui.control.Label
        numVotes_slider               matlab.ui.control.Slider
        numVotes_checkbox             matlab.ui.control.CheckBox
        edit_finalRefinementRadius    matlab.ui.control.EditField
        text_finalRefinementRadius    matlab.ui.control.Label
        edit_ObjectNumber             matlab.ui.control.EditField
        text_ObjectNumber             matlab.ui.control.Label
        popupmenu_ProcessIndex        matlab.ui.control.DropDown
        text_GenerateSummationChannelProcess  matlab.ui.control.Label
        tightness_checkbox            matlab.ui.control.CheckBox
        tightness_panel               matlab.ui.container.Panel
        tightness_slider              matlab.ui.control.Slider
        tightness_display             matlab.ui.control.Label
        checkbox_useSummationChannel  matlab.ui.control.CheckBox
        checkbox_applytoall           matlab.ui.control.CheckBox
        pushbutton_done               matlab.ui.control.Button
        pushbutton_cancel             matlab.ui.control.Button
        text_processName              matlab.ui.control.Label
        text_copyright                matlab.ui.control.Label
        uipanel_1                     matlab.ui.container.ButtonGroup
        listbox_availableChannels     matlab.ui.control.ListBox
        listbox_selectedChannels      matlab.ui.control.ListBox
        pushbutton_delete             matlab.ui.control.Button
        pushbutton_select             matlab.ui.control.Button
        text_channels1                matlab.ui.control.Label
        text_channels2                matlab.ui.control.Label
        checkbox_all                  matlab.ui.control.CheckBox
        axes_help                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.DeleteFcn = createCallbackFcn(app, @figure1_DeleteFcn, true);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function msaSegmentationProcessGUI_OpeningFcn(app, varargin)
            % --- Executes just before msaSegmentationProcessGUI is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            %DEBUG - Hillary: Adding listbox_availableChannels to handles
            if ~isfield(handles, 'listbox_availableChannels')
                handles.listbox_availableChannels = app.listbox_availableChannels;
            end
            
            processGUI_OpeningFcn(hObject, eventdata, handles, varargin{:},'initChannel',1);
            
            % Parameter setup
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            funParams = userData.crtProc.funParams_;
            
            
            % set GUI with Parameters
            
            if funParams.tightness == -1
                handles.tightness_checkbox.Value = 0;
                handles.tightness_slider.Enable = 'off';
                handles.tightness_slider.Value = .5;
                handles.tightness_display.String = 'Inactive';
                handles.tightness_display.Enable = 'off';
            elseif funParams.tightness <=1 && funParams.tightness >= 0
                handles.tightness_checkbox.Value = 1;
                handles.tightness_slider.Enable = 'on';
                handles.tightness_slider.Value = funParams.tightness;
                handles.tightness_display.String = num2str(handles.tightness_slider.Value);
            end
            
            if funParams.numVotes == -1
                handles.numVotes_checkbox.Value = 0;
                handles.numVotes_slider.Enable = 'off';
                handles.numVotes_slider.Value = 22;
                handles.numVotes_display.String = 'Inactive';
                handles.numVotes_display.Enable = 'off';
            elseif funParams.numVotes <=42 && funParams.numVotes >= 0
                handles.numVotes_checkbox.Value = 1;
                handles.numVotes_slider.Enable = 'on';
                handles.numVotes_slider.Value = funParams.numVotes;
                handles.numVotes_display.String = num2str(handles.numVotes_slider.Value);
            end
            
            set(handles.edit_ObjectNumber, 'String',num2str(funParams.ObjectNumber))
            set(handles.edit_finalRefinementRadius, 'String',num2str(funParams.finalRefinementRadius))
            
            % set GUI with popupmenu_ProcessIndex
            sumChanProc =  cellfun(@(x) isa(x,'GenerateSummationChannelProcess'),userData.MD.processes_);
            sumChanProcID=find(sumChanProc);
            sumChanProcNames = cellfun(@(x) x.getName(),userData.MD.processes_(sumChanProc),'Unif',false);
            sumChanProcString = vertcat('Choose later',sumChanProcNames(:));
            sumChanProcData=horzcat({[]},num2cell(sumChanProcID));
            sumChanProcValue = find(cellfun(@(x) isequal(x,funParams.ProcessIndex),sumChanProcData));
            if isempty(sumChanProcValue), sumChanProcValue = 1; end
            set(handles.popupmenu_ProcessIndex,'String',sumChanProcString,...
                'UserData',sumChanProcData,'Value',sumChanProcValue);
            
            % Update channels listboxes depending on the selected process
            popupmenu_ProcessIndex_Callback(app, [])
            
            if funParams.useSummationChannel == 1
                handles.text_GenerateSummationChannelProcess.Enable = 'on';
                handles.popupmenu_ProcessIndex.Enable = 'on';
            elseif funParams.useSummationChannel == 0
                handles.text_GenerateSummationChannelProcess.Enable = 'off';
                handles.popupmenu_ProcessIndex.Enable = 'off';
            end
            
            % Update user data and GUI data
            handles.output = hObject;
            set(handles.figure1, 'UserData', userData);
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_useSummationChannel
        function checkbox_useSummationChannel_Callback(app, event)
            % --- Executes on button press in useSummationChannel_checkbox.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to useSummationChannel_checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of useSummationChannel_checkbox
            if handles.checkbox_useSummationChannel.Value == 0
                handles.text_GenerateSummationChannelProcess.Enable = 'off';
                handles.popupmenu_ProcessIndex.Enable = 'off';
            else
                handles.text_GenerateSummationChannelProcess.Enable = 'on';
                handles.popupmenu_ProcessIndex.Enable = 'on';
            end
        end

        % Callback function
        function figure1_DeleteFcn(app, event)
            % --- Executes during object deletion, before destroying properties.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            
            if isfield(userData, 'helpFig') && ishandle(userData.helpFig)
               delete(userData.helpFig)
            end
            
            set(handles.figure1, 'UserData', userData);
            guidata(hObject,handles);
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)
            % --- Executes on key press with focus on figure1 and none of its controls.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            if strcmp(eventdata.Key, 'return')
                pushbutton_done_Callback(app, []);
            end
        end

        % Value changed function: numVotes_checkbox
        function numVotes_checkbox_Callback(app, event)
            % --- Executes on button press in numVotes_checkbox.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to numVotes_checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of numVotes_checkbox
            if handles.numVotes_checkbox.Value == 0
                handles.numVotes_slider.Enable = 'off';
                handles.numVotes_display.String = 'Inactive';
                handles.numVotes_display.Enable = 'off';
            
                handles.tightness_checkbox.Value = 1;
                handles.tightness_slider.Enable = 'on';
                handles.tightness_display.String = num2str(handles.tightness_slider.Value);
                handles.tightness_display.Enable = 'on';
            else
                handles.numVotes_slider.Enable = 'on';
                handles.numVotes_display.String = num2str(handles.numVotes_slider.Value);
                handles.numVotes_display.Enable = 'on';
            
                handles.tightness_checkbox.Value = 0;
                handles.tightness_slider.Enable = 'off';
                handles.tightness_display.String = 'Inactive';
                handles.tightness_display.Enable = 'off';
            end
        end

        % Value changed function: numVotes_slider
        function numVotes_slider_Callback(app, event)
            % --- Executes on numVotes slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to numVotes_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            handles.numVotes_display.String = num2str(round(handles.numVotes_slider.Value));
        end

        % Value changed function: popupmenu_ProcessIndex
        function popupmenu_ProcessIndex_Callback(app, event)
            % --- Executes on selection change in popupmenu_ProcessIndex.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            %DEBUG Hillary: Adding listbox_availableChannels to handles
            if ~isfield(handles, 'listbox_availableChannels')
                handles.listbox_availableChannels = app.listbox_availableChannels;
            end

            % Retrieve selected process ID
            props= get(handles.popupmenu_ProcessIndex,{'UserData','Value'});
            procID = props{1}{props{2}};
            
            % Read process and check available channels
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            if isempty(procID)
                allChannelIndex=1:numel(userData.MD.channels_);
            else
                allChannelIndex = find(userData.MD.processes_{procID}.checkChannelOutput);
            end
            
            % Set up available channels listbox
            if ~isempty(allChannelIndex)
                if isempty(procID)
                    channelString = userData.MD.getChannelPaths(allChannelIndex);
                else
                    channelString = userData.MD.processes_{procID}.outFilePaths_(1,allChannelIndex);
                end
            else
                channelString = {};
            end

            set(handles.listbox_availableChannels,'Items',channelString,'ItemsData',allChannelIndex);
            
            % Set up selected channels listbox
            channelIndex = get(handles.listbox_selectedChannels, 'UserData');
            channelIndex = intersect(channelIndex,allChannelIndex);
            if ~isempty(channelIndex)
                if isempty(procID)
                    channelString = userData.MD.getChannelPaths(channelIndex);
                else
                    channelString = userData.MD.processes_{procID}.outFilePaths_(1,channelIndex);
                end
            else
                channelString = {};
            end
            set(handles.listbox_selectedChannels,'String',channelString,'UserData',channelIndex);
        end

        % Button pushed function: pushbutton_cancel
        function pushbutton_cancel_Callback(app, event)
            % --- Executes on button press in pushbutton_cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_cancel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            delete(handles.figure1);
        end

        % Button pushed function: pushbutton_done
        function pushbutton_done_Callback(app, event)
            % --- Executes on button press in pushbutton_done.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>


            %  Check user input --------
            if isempty(get(handles.listbox_selectedChannels, 'String'))
                errordlg('Please select at least one input channel from ''Available Channels''.','Setting Error','modal')
                return;
            end
            
            if isnan(str2double(get(handles.edit_ObjectNumber, 'String'))) ...
                || str2double(get(handles.edit_ObjectNumber, 'String')) < 0
              errordlg('Please provide a valid input for ''Objec Number''.','Setting Error','modal');
              return;
            end
            
            if isnan(str2double(get(handles.edit_finalRefinementRadius, 'String'))) ...
                || str2double(get(handles.edit_finalRefinementRadius, 'String')) < 0
              errordlg('Please provide a valid input for ''Final Refinement Radius''.','Setting Error','modal');
              return;
            end
            
            %  Process Sanity check ( only check underlying data )
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            try
                userData.crtProc.sanityCheck;
            catch ME
                errordlg([ME.message 'Please double check your data.'],...
                            'Setting Error','modal');
                return;
            end
            
            % Retrieve GUI-defined parameters
            channelIndex = get(handles.listbox_selectedChannels, 'Userdata');
            funParams.ChannelIndex = channelIndex;
            
            funParams.ObjectNumber = str2double(get(handles.edit_ObjectNumber, 'String'));
            funParams.finalRefinementRadius = str2double(get(handles.edit_finalRefinementRadius, 'String'));
            
            if handles.tightness_checkbox.Value == 1
                funParams.tightness = str2double(handles.tightness_display.String);
            else
                funParams.tightness = -1;
            end
            
            if handles.numVotes_checkbox.Value == 1
                funParams.numVotes = str2double(handles.numVotes_display.String);
            else
                funParams.numVotes = -1;
            end
            
            % Retrieve GenerateSummationChannelProcess index
            if handles.checkbox_useSummationChannel.Value == 1
              funParams.useSummationChannel = 1;
              props=get(handles.popupmenu_ProcessIndex,{'UserData','Value'});
              funParams.ProcessIndex = props{1}{props{2}};
            else
              funParams.useSummationChannel = 0;
            end
            
            % Set parameters and update main window
            processGUI_ApplyFcn(hObject, eventdata, handles,funParams);
        end

        % Value changed function: tightness_checkbox
        function tightness_checkbox_Callback(app, event)
            % --- Executes on button press in tightness_checkbox.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tightness_checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of tightness_checkbox
            if handles.tightness_checkbox.Value == 0
                handles.tightness_slider.Enable = 'off';
                handles.tightness_display.String = 'Inactive';
                handles.tightness_display.Enable = 'off';
            
                handles.numVotes_checkbox.Value = 1;
                handles.numVotes_slider.Enable = 'on';
                handles.numVotes_display.String = num2str(handles.numVotes_slider.Value);
                handles.numVotes_display.Enable = 'on';
            else
                handles.tightness_slider.Enable = 'on';
                handles.tightness_display.String = num2str(handles.tightness_slider.Value);
                handles.tightness_display.Enable = 'on';
            
                handles.numVotes_checkbox.Value = 0;
                handles.numVotes_slider.Enable = 'off';
                handles.numVotes_display.String = 'Inactive';
                handles.numVotes_display.Enable = 'off';
            end
        end

        % Value changed function: tightness_slider
        function tightness_slider_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tightness_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            handles.tightness_display.String = num2str(round((handles.tightness_slider.Value)*10)/10);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Setting - Mask Refinement';
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.axes_help = uiaxes(app.figure1);
            app.axes_help.Tag = 'axes_help';
            app.axes_help.Visible = 'off';
            app.uipanel_1 = uibuttongroup(app.figure1);
            app.uipanel_1.Title = 'Input Channels';
            app.uipanel_1.Tag = 'uipanel_1';
            app.checkbox_all = uicheckbox(app.uipanel_1);
            app.checkbox_all.Tag = 'checkbox_all';
            app.checkbox_all.Text = ' Select all channels';
            app.text_channels2 = uilabel(app.uipanel_1);
            app.text_channels2.Tag = 'text_channels2';
            app.text_channels2.Text = 'Selected Channels';
            app.text_channels1 = uilabel(app.uipanel_1);
            app.text_channels1.Tag = 'text_channels1';
            app.text_channels1.Text = 'Available Input Channels';
            app.pushbutton_select = uibutton(app.uipanel_1, 'push');
            app.pushbutton_select.Tag = 'pushbutton_select';
            app.pushbutton_select.Text = 'Select ->';
            app.pushbutton_delete = uibutton(app.uipanel_1, 'push');
            app.pushbutton_delete.Tag = 'pushbutton_delete';
            app.pushbutton_delete.Text = 'Delete';
            app.listbox_selectedChannels = uilistbox(app.uipanel_1);
            app.listbox_selectedChannels.Items = {};
            app.listbox_selectedChannels.Tag = 'listbox_selectedChannels';
            app.listbox_selectedChannels.Value = {};
            app.listbox_availableChannels = uilistbox(app.uipanel_1);
            app.listbox_availableChannels.Items = {'Item 1', 'Item 2'};
            app.text_copyright = uilabel(app.figure1);
            app.text_copyright.Tag = 'text_copyright';
            app.text_copyright.Text = 'copyright info';
            app.text_processName = uilabel(app.figure1);
            app.text_processName.Tag = 'text_processName';
            app.text_processName.Text = 'Step x: ';
            app.pushbutton_cancel = uibutton(app.figure1, 'push');
            app.pushbutton_cancel.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_cancel_Callback, true);
            app.pushbutton_cancel.Tag = 'pushbutton_cancel';
            app.pushbutton_cancel.Text = 'Cancel';
            app.pushbutton_done = uibutton(app.figure1, 'push');
            app.pushbutton_done.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_done_Callback, true);
            app.pushbutton_done.Tag = 'pushbutton_done';
            app.pushbutton_done.Text = 'Apply';
            app.checkbox_applytoall = uicheckbox(app.figure1);
            app.checkbox_applytoall.Tag = 'checkbox_applytoall';
            app.checkbox_applytoall.Text = 'Apply settings to all movies';
            app.checkbox_applytoall.Value = true;
            app.uipanel_2 = uibuttongroup(app.figure1);
            app.uipanel_2.Title = 'Parmeters';
            app.uipanel_2.Tag = 'uipanel_2';
            app.checkbox_useSummationChannel = uicheckbox(app.uipanel_2);
            app.checkbox_useSummationChannel.ValueChangedFcn = createCallbackFcn(app, @checkbox_useSummationChannel_Callback, true);
            app.checkbox_useSummationChannel.Tag = 'checkbox_useSummationChannel';
            app.checkbox_useSummationChannel.Text = 'Use Output from Summation Channel';
            app.tightness_panel = uipanel(app.uipanel_2);
            app.tightness_panel.Title = 'Tightness';
            app.tightness_panel.Tag = 'tightness_panel';
            app.tightness_display = uilabel(app.tightness_panel);
            app.tightness_display.Tag = 'tightness_display';
            app.tightness_display.Text = 'Static Text';
            app.tightness_slider = uislider(app.tightness_panel);
            app.tightness_slider.Limits = [0 1];
            app.tightness_slider.MajorTicks = [];
            app.tightness_slider.ValueChangedFcn = createCallbackFcn(app, @tightness_slider_Callback, true);
            app.tightness_slider.MinorTicks = [];
            app.tightness_slider.Tag = 'tightness_slider';
            app.tightness_checkbox = uicheckbox(app.uipanel_2);
            app.tightness_checkbox.ValueChangedFcn = createCallbackFcn(app, @tightness_checkbox_Callback, true);
            app.tightness_checkbox.Tag = 'tightness_checkbox';
            app.tightness_checkbox.Text = 'Manually Set Tightness';
            app.text_GenerateSummationChannelProcess = uilabel(app.uipanel_2);
            app.text_GenerateSummationChannelProcess.Tag = 'text_GenerateSummationChannelProcess';
            app.text_GenerateSummationChannelProcess.Text = 'Generate Summation Channel Process';
            app.popupmenu_ProcessIndex = uidropdown(app.uipanel_2);
            app.popupmenu_ProcessIndex.Items = {};
            app.popupmenu_ProcessIndex.ValueChangedFcn = createCallbackFcn(app, @popupmenu_ProcessIndex_Callback, true);
            app.popupmenu_ProcessIndex.Tag = 'popupmenu_ProcessIndex';
            app.popupmenu_ProcessIndex.Value = {};
            app.text_ObjectNumber = uilabel(app.uipanel_2);
            app.text_ObjectNumber.Tag = 'text_ObjectNumber';
            app.text_ObjectNumber.Text = 'Object Number';
            app.edit_ObjectNumber = uieditfield(app.uipanel_2, 'text');
            app.edit_ObjectNumber.Tag = 'edit_ObjectNumber';
            app.text_finalRefinementRadius = uilabel(app.uipanel_2);
            app.text_finalRefinementRadius.Tag = 'text_finalRefinementRadius';
            app.text_finalRefinementRadius.Text = 'Final Refinement Radius';
            app.edit_finalRefinementRadius = uieditfield(app.uipanel_2, 'text');
            app.edit_finalRefinementRadius.Tag = 'edit_finalRefinementRadius';
            app.numVotes_checkbox = uicheckbox(app.uipanel_2);
            app.numVotes_checkbox.ValueChangedFcn = createCallbackFcn(app, @numVotes_checkbox_Callback, true);
            app.numVotes_checkbox.Tag = 'numVotes_checkbox';
            app.numVotes_checkbox.Text = 'Manually Set Number of Votes';
            app.numVotes_panel = uipanel(app.uipanel_2);
            app.numVotes_panel.Title = 'Number of Votes';
            app.numVotes_panel.Tag = 'numVotes_panel';
            app.numVotes_slider = uislider(app.numVotes_panel);
            app.numVotes_slider.Limits = [0 42];
            app.numVotes_slider.MajorTicks = [];
            app.numVotes_slider.ValueChangedFcn = createCallbackFcn(app, @numVotes_slider_Callback, true);
            app.numVotes_slider.MinorTicks = [];
            app.numVotes_slider.Tag = 'numVotes_slider';
            app.numVotes_display = uilabel(app.numVotes_panel);
            app.numVotes_display.Tag = 'numVotes_display';
            app.numVotes_display.Text = 'Static Text';
            app.text26 = uilabel(app.numVotes_panel);
            app.text26.Tag = 'text26';
            app.text26.Text = 'A higher value will give a smaller mask.';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MsaSegmentationProcessGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)msaSegmentationProcessGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end