classdef thresholdProcessGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        uipanel_1                      matlab.ui.container.ButtonGroup
        checkbox_invert                matlab.ui.control.CheckBox
        text16                         matlab.ui.control.Label
        edit_GaussFilterSigma          matlab.ui.control.EditField
        text_GaussFilterSigma          matlab.ui.control.Label
        text_nFrames                   matlab.ui.control.Label
        text11                         matlab.ui.control.Label
        slider_frameNumber             matlab.ui.control.Slider
        edit_frameNumber               matlab.ui.control.EditField
        checkbox_preview               matlab.ui.control.CheckBox
        checkbox_auto                  matlab.ui.control.CheckBox
        uipanel_fixedThreshold         matlab.ui.container.Panel
        text_percentile                matlab.ui.control.Label
        checkbox_is_percentile         matlab.ui.control.CheckBox
        text_thresholdValue            matlab.ui.control.Label
        pushbutton_set_threshold       matlab.ui.control.Button
        listbox_thresholdValues        matlab.ui.control.ListBox
        slider_threshold               matlab.ui.control.Slider
        edit_threshold                 matlab.ui.control.EditField
        uipanel_automaticThresholding  matlab.ui.container.Panel
        checkbox_exclude_zero          matlab.ui.control.CheckBox
        text18                         matlab.ui.control.Label
        edit_exclude_outliers_k_sigma  matlab.ui.control.EditField
        checkbox_exclude_outliers      matlab.ui.control.CheckBox
        checkbox_preThreshold          matlab.ui.control.CheckBox
        text_thresholdingMethod        matlab.ui.control.Label
        popupmenu_thresholdingMethod   matlab.ui.control.DropDown
        checkbox_max                   matlab.ui.control.CheckBox
        edit_jump                      matlab.ui.control.EditField
        checkbox_applytoall            matlab.ui.control.CheckBox
        pushbutton_done                matlab.ui.control.Button
        pushbutton_cancel              matlab.ui.control.Button
        text_processName               matlab.ui.control.Label
        text_copyright                 matlab.ui.control.Label
        uipanel_channels               matlab.ui.container.ButtonGroup
        listbox_availableChannels      matlab.ui.control.ListBox
        listbox_selectedChannels       matlab.ui.control.ListBox
        pushbutton_delete              matlab.ui.control.Button
        pushbutton_select              matlab.ui.control.Button
        text_body4                     matlab.ui.control.Label
        text_body5                     matlab.ui.control.Label
        checkbox_all                   matlab.ui.control.CheckBox
        axes_help                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function edit_preThreshold_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit_preThreshold (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function setPercentileMode(app, handles, isPercentile)
                percent = '%';
                set(handles.text_percentile,'String',percent(isPercentile));
                set(handles.checkbox_is_percentile,'Value',isPercentile);
        end
        
        function [thresholdValues, isPercentile] = stringToThresholds(app, thresholdStrings)
                if(ischar(thresholdStrings))
                    thresholdStrings = {thresholdStrings};
                end
                isPercentile = cellfun(@(x) x(end) == '%',thresholdStrings);
                thresholdStrings(isPercentile) = cellfun(@(x) x(1:end-1),thresholdStrings(isPercentile),'UniformOutput',false);
                thresholdValues = str2double(thresholdStrings);
        end
        
        function strings = thresholdsToString(app, thresholdValues, isPercentile)
                strings = num2cell(thresholdValues);
                strings = cellfun(@num2str,strings,'UniformOutput',false);
                percent = cell(size(strings));
                [percent{isPercentile}] = deal('%');
                strings = strcat(strings,percent);
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.DeleteFcn = createCallbackFcn(app, @figure1_DeleteFcn, true);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function thresholdProcessGUI_OpeningFcn(app, varargin)
            % --- Executes just before thresholdProcessGUI is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU

            %DEBUG EDIT - Hillary: adding listbox_availableChannels to
            %handles
            handles.listbox_availableChannels = app.listbox_availableChannels;
            
            processGUI_OpeningFcn(hObject, eventdata, handles, varargin{:},'initChannel',0);
            
            % ---------------------- Channel Setup -------------------------
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            funParams = userData.crtProc.funParams_;
            
            % Set up available input channels
            set(handles.listbox_availableChannels,'Items',userData.MD.getChannelPaths(), ...
                'ItemsData',1:numel(userData.MD.channels_));
            
            channelIndex = funParams.ChannelIndex;
            
            % Find any parent process
            userData.parentProc = userData.crtPackage.getParent(userData.procID);
            if numel(userData.parentProc) > 1
                userData.parentProc = userData.parentProc(end); % use last parentProc as parentProc
            end
            if isempty(userData.crtPackage.processes_{userData.procID}) && ~isempty(userData.parentProc)
                % Check existence of all parent processes
                emptyParentProc = any(cellfun(@isempty,userData.crtPackage.processes_(userData.parentProc)));
                if ~emptyParentProc
                    % Intersect channel index with channel index of parent processes
                    parentChannelIndex = @(x) userData.crtPackage.processes_{x}.funParams_.ChannelIndex;
                    for i = userData.parentProc
                        channelIndex = intersect(channelIndex,parentChannelIndex(i));
                    end
                end
            
            end
            
            
            if ~isempty(channelIndex)
                channelString = userData.MD.getChannelPaths(channelIndex);
            else
                channelString = {};
            end
            
            set(handles.listbox_selectedChannels,'String',channelString,...
                'UserData',channelIndex);
            
            set(handles.edit_GaussFilterSigma,'String',funParams.GaussFilterSigma);
            
            threshMethods = userData.crtProc.getMethods();
            set(handles.popupmenu_thresholdingMethod,'String',{threshMethods(:).name},...
                'Value',funParams.MethodIndx);
            
            if(~isfield(funParams,'PreThreshold'))
                funParams.PreThreshold = false;
            end
            
            if(~isfield(funParams,'ExcludeOutliers'))
                funParams.ExcludeOutliers = false;
            end
            
            if(~isfield(funParams,'ExcludeZero'))
                funParams.ExcludeZero = false;
            end
            
            if(~isfield(funParams,'Invert'))
                funParams.Invert = false;
            end
            
            set(handles.checkbox_invert, 'Value', funParams.Invert);
            
            useAutomatic = isempty(funParams.ThresholdValue) || funParams.PreThreshold;
            useFixed = ~isempty(funParams.ThresholdValue) || funParams.PreThreshold;
            
            if useAutomatic
            
                set(handles.checkbox_auto, 'Value', 1);
                set(get(handles.uipanel_automaticThresholding,'Children'),'Enable','on');
                if funParams.MaxJump
                    set(handles.checkbox_max, 'Value', 1);
                    set(handles.edit_jump, 'Enable','on','String',funParams.MaxJump);
                else
                    set(handles.edit_jump, 'Enable', 'off');
                end
                if funParams.PreThreshold
                    set(handles.checkbox_preThreshold, 'Value', 1);
                end
            
                if funParams.ExcludeOutliers
                    set(handles.checkbox_exclude_outliers,'Value',1);
                    set(handles.edit_exclude_outliers_k_sigma,'Enable','on','String',num2str(funParams.ExcludeOutliers));
                else
                    set(handles.checkbox_exclude_outliers,'Value',0);
                    set(handles.edit_exclude_outliers_k_sigma,'Enable','off','String','');
                end
            
                set(handles.checkbox_exclude_zero,'Value',funParams.ExcludeZero);
            
            else
                set(handles.checkbox_auto, 'Value', 0);
                set(get(handles.uipanel_automaticThresholding,'Children'),'Enable','off');
            
            end
            
            if useFixed
                if(isempty(funParams.ThresholdValue))
                    funParams.ThresholdValue(1) = 0;
                end
                if(isempty(funParams.IsPercentile))
                    funParams.IsPercentile = false(size(funParams.ThresholdValue));
                end
            %     set(handles.checkbox_auto, 'Value', 0);
                set(get(handles.uipanel_fixedThreshold,'Children'),'Enable','on');
            %     set(get(handles.uipanel_automaticThresholding,'Children'),'Enable','off');
                set(handles.listbox_thresholdValues, 'String', thresholdsToString(app, funParams.ThresholdValue,funParams.IsPercentile));
                userData.thresholdValue=funParams.ThresholdValue(1);
                set(handles.slider_threshold, 'Value',funParams.ThresholdValue(1))
                set(handles.checkbox_is_percentile,'Value',funParams.IsPercentile(1));
            else
                set(get(handles.uipanel_fixedThreshold,'Children'),'Enable','off');
                nSelectedChannels  = numel(get(handles.listbox_selectedChannels, 'String'));
                set(handles.listbox_thresholdValues, 'String',...
                    num2cell(zeros(1,nSelectedChannels)));
                userData.thresholdValue=0;
            end
            
            % Initialize the frame number slider and eidt
            nFrames=userData.MD.nFrames_;
            if nFrames > 1
                set(handles.slider_frameNumber,'Value',1,'Min',1,...
                    'Max',nFrames,'SliderStep',[1/double(nFrames)  10/double(nFrames)]);
            else
                set(handles.slider_frameNumber,'Enable','off');
            end
            set(handles.text_nFrames,'String',['/ ' num2str(nFrames)]);
            set(handles.edit_frameNumber,'Value',1);
            
            
            % Initialize previewing constants
            userData.previewFig =-1;
            userData.chanIndx = 0;
            userData.imIndx=0;
            
            % Update user data and GUI data
            handles.output = hObject;
            set(hObject, 'UserData', userData);
            guidata(hObject, handles);
            update_data(app, []);
        end

        % Value changed function: checkbox_all
        function checkbox_all_Callback(app, event)
            % --- Executes on button press in checkbox_all.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
          

            % Hint: get(hObject,'Value') returns toggle state of checkbox_all
            contents1 = get(handles.listbox_availableChannels, 'String');
            
            chanIndex1 = get(handles.listbox_availableChannels, 'Userdata');
            chanIndex2 = get(handles.listbox_selectedChannels, 'Userdata');
            
            % Return if listbox1 is empty
            if isempty(contents1)
                return;
            end
            
            switch get(hObject,'Value')
                case 1
                    set(handles.listbox_selectedChannels, 'String', contents1);
                    chanIndex2 = chanIndex1;
                    thresholdValues =zeros(1,numel(chanIndex1));
                case 0
                    set(handles.listbox_selectedChannels, 'String', {}, 'Value',1);
                    chanIndex2 = [ ];
                    thresholdValues = [];
            end
            set(handles.listbox_selectedChannels, 'UserData', chanIndex2);
            set(handles.listbox_thresholdValues,'String',num2cell(thresholdValues))
            update_data(hObject,eventdata,handles);
        end

        % Value changed function: checkbox_auto
        function checkbox_auto_Callback(app, event)
            % --- Executes on button press in checkbox_auto.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_auto
            if get(hObject, 'Value')
                set(get(handles.uipanel_automaticThresholding,'Children'),'Enable','on');
                if ~get(handles.checkbox_preThreshold,'Value')
            %         set(handles.edit_preThreshold,'Enable','off');
                    set(get(handles.uipanel_fixedThreshold,'Children'),'Enable','off');
                end
                if ~get(handles.checkbox_max,'Value'), set(handles.edit_jump,'Enable','off'); end
            else
                set(get(handles.uipanel_automaticThresholding,'Children'),'Enable','off');
                set(get(handles.uipanel_fixedThreshold,'Children'),'Enable','on')
                set(handles.checkbox_max, 'Value', 0);
                set(handles.checkbox_preThreshold, 'Value', 0);
                set(handles.checkbox_applytoall, 'Value',0);
                set(handles.checkbox_preview, 'Value',1);
            end
            update_data(hObject,eventdata,handles);
        end

        % Value changed function: checkbox_exclude_outliers
        function checkbox_exclude_outliers_Callback(app, event)
            % --- Executes on button press in checkbox_exclude_outliers.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_exclude_outliers (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_exclude_outliers
            if get(hObject,'Value')
                set(handles.edit_exclude_outliers_k_sigma,'Enable','on','String','3');
            else
                set(handles.edit_exclude_outliers_k_sigma,'Enable','off');
            end
        end

        % Value changed function: checkbox_invert
        function checkbox_invert_Callback(app, event)
            % --- Executes on button press in checkbox_invert.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_invert (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_invert
            update_data(app, event);
        end

        % Value changed function: checkbox_is_percentile
        function checkbox_is_percentile_Callback(app, event)
            % --- Executes on button press in checkbox_is_percentile.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_is_percentile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_is_percentile
            if get(handles.checkbox_is_percentile,'Value')
                % Convert threshold values into percentiles
                thresholdValue = get(handles.slider_threshold, 'Value');
                userData = get(handles.figure1, 'UserData');
                threshPercent = sum(userData.imData(:) < thresholdValue)/numel(userData.imData(:))*100;
                set(handles.slider_threshold,'Value',threshPercent,'Min',0,'Max',100, ...
                    'SliderStep',[0.01 0.1] ...
                    );
                set(handles.edit_threshold,'String',threshPercent);
                set(handles.text_percentile,'String','%');
                update_data(hObject,eventdata, handles)
            else
                % Convert percentile to threshold values
                thresholdPercent = get(handles.slider_threshold, 'Value');
                userData = get(handles.figure1, 'UserData');
                thresholdValue = prctile(userData.imData(:),thresholdPercent);
                maxThresholdValue=max(max(userData.imData(:)),1);
                minThresholdValue=min(min(userData.imData(:)),0);
                set(handles.slider_threshold,'Value',thresholdValue,'Min',minThresholdValue,'Max',maxThresholdValue, ...
                    'SliderStep',[1/double(maxThresholdValue)  10/double(maxThresholdValue)]);
                set(handles.edit_threshold,'String',thresholdValue);
                set(handles.text_percentile,'String','');
            end
        end

        % Value changed function: checkbox_max
        function checkbox_max_Callback(app, event)
            % --- Executes on button press in checkbox_max.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if get(hObject, 'value')
                set(handles.edit_jump, 'Enable', 'on');
            else
                set(handles.edit_jump, 'Enable', 'off');
            end
        end

        % Value changed function: checkbox_preThreshold
        function checkbox_preThreshold_Callback(app, event)
            % --- Executes on button press in checkbox_preThreshold.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_preThreshold (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_preThreshold
            if get(hObject, 'value')
                set(get(handles.uipanel_fixedThreshold,'Children'),'Enable','on')
            %     set(handles.checkbox_max, 'Value', 0);
            %     set(handles.checkbox_preThreshold, 'Value', 0);
            %     set(handles.checkbox_applytoall, 'Value',0);
                set(handles.checkbox_preview, 'Value',1);
            else
                set(get(handles.uipanel_fixedThreshold,'Children'),'Enable','off');
            %     set(handles.edit_preThreshold, 'Enable', 'off');
            end
            update_data(app, event);
        end

        % Value changed function: checkbox_preview
        function checkbox_preview_Callback(app, event)
            % --- Executes on button press in checkbox_preview.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if get(handles.checkbox_preview,'Value'),
                update_data(hObject,eventdata,handles);
            else
                userData = get(handles.figure1, 'UserData');
                if ishandle(userData.previewFig), delete(userData.previewFig); end
                % Save data and update graphics
                set(handles.figure1,'UserData',userData);
                guidata(hObject, handles);
            end
        end

        % Value changed function: edit_exclude_outliers_k_sigma
        function edit_exclude_outliers_k_sigma_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_exclude_outliers_k_sigma (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_exclude_outliers_k_sigma as text
            %        str2double(get(hObject,'String')) returns contents of edit_exclude_outliers_k_sigma as a double
            userData = get(hObject, 'UserData');
            k_sigma = str2double(get(hObject,'String'));
            try
                validateattributes(k_sigma,{'numeric'},{'nonnan','positive'})
                userData.k_sigma = k_sigma;
                set(hObject,'UserData',userData);
            catch err
                warndlg('Please provide a valid sigma multiplier','Setting Error','modal');
                if(isempty(userData.k_sigma))
                    userData.k_sigma = 3;
                end
                set(hObject,'String',userData.k_sigma);
            end
        end

        % Callback function
        function figure1_DeleteFcn(app, event)
            % --- Executes during object deletion, before destroying properties.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Notify the package GUI that the setting panel is closed
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            
            if ishandle(userData.helpFig), delete(userData.helpFig); end
            if ishandle(userData.previewFig), delete(userData.previewFig); end
            
            set(handles.figure1, 'UserData', userData);
            guidata(hObject,handles);
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)
            % --- Executes on key press with focus on figure1 and none of its controls.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if strcmp(eventdata.Key, 'return')
                pushbutton_done_Callback(handles.pushbutton_done, [], handles);
            end
        end

        % Value changed function: edit_frameNumber, slider_frameNumber
        function imageNumber_edition(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Retrieve the value of the selected image
            if strcmp(get(hObject,'Tag'),'edit_imageNumber')
                imageNumber = str2double(get(handles.edit_frameNumber, 'String'));
            else
                imageNumber = get(handles.slider_frameNumber, 'Value');
            end
            imageNumber=round(imageNumber);
            
            % Check the validity of the supplied threshold
            if isnan(imageNumber) || imageNumber < 0 || imageNumber > get(handles.slider_frameNumber,'Max')
                warndlg('Please provide a valid coefficient.','Setting Error','modal');
            end
            
            set(handles.slider_frameNumber,'Value',imageNumber);
            set(handles.edit_frameNumber,'String',imageNumber);
            
            % Save data and update graphics
            guidata(hObject, handles);
            update_data(hObject,eventdata,handles);
        end

        % Button pushed function: pushbutton_cancel
        function pushbutton_cancel_Callback(app, event)
            % --- Executes on button press in pushbutton_cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Delete figure
            delete(handles.figure1);
        end

        % Button pushed function: pushbutton_delete
        function pushbutton_delete_Callback(app, event)
            % --- Executes on button press in pushbutton_delete.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Call back function of 'delete' button
            contents = get(handles.listbox_selectedChannels,'String');
            id = get(handles.listbox_selectedChannels,'Value');
            
            % Return if list is empty
            if isempty(contents) || isempty(id)
                return;
            end
            
            % Delete selected item
            contents(id) = [ ];
            
            % Delete userdata
            chanIndex2 = get(handles.listbox_selectedChannels, 'Userdata');
            chanIndex2(id) = [ ];
            set(handles.listbox_selectedChannels, 'Userdata', chanIndex2);
            
            % Update thresholdValues
            % thresholdValues = cellfun(@str2num,get(handles.listbox_thresholdValues,'String'));
            [thresholdValues,isPercentile] = stringToThresholds(app, get(handles.listbox_thresholdValues,'String'));
            thresholdValues(id) = [];
            isPercentile(id) = [];
            set(handles.listbox_thresholdValues,'String',thresholdsToString(app, thresholdValues,isPercentile));
            
            % Point 'Value' to the second last item in the list once the
            % last item has been deleted
            if (id >length(contents) && id>1)
                set(handles.listbox_selectedChannels,'Value',length(contents));
                set(handles.listbox_thresholdValues,'Value',length(contents));
            end
            % Refresh listbox
            set(handles.listbox_selectedChannels,'String',contents);
            update_data(hObject,eventdata,handles);
        end

        % Button pushed function: pushbutton_done
        function pushbutton_done_Callback(app, event)
            % --- Executes on button press in pushbutton_done.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Call back function of 'Apply' button
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            
            % -------- Check user input --------
            if isempty(get(handles.listbox_selectedChannels, 'String'))
               errordlg('Please select at least one input channel from ''Available Channels''.','Setting Error','modal')
                return;
            end
            channelIndex = get (handles.listbox_selectedChannels, 'Userdata');
            funParams.ChannelIndex = channelIndex;
            
            gaussFilterSigma = str2double(get(handles.edit_GaussFilterSigma, 'String'));
            if isnan(gaussFilterSigma) || gaussFilterSigma < 0
                errordlg(['Please provide a valid input for '''...
                    get(handles.text_GaussFilterSigma,'String') '''.'],'Setting Error','modal');
                return;
            end
            funParams.GaussFilterSigma=gaussFilterSigma;
            
            funParams.Invert = get(handles.checkbox_invert,'Value');
            
            % Automatic and fixed are not mutually exclusive.
            % If PreThreshold, one could use both automatic and fixed thresholds.
            useAutomatic = get(handles.checkbox_auto, 'value');
            useFixed = ~useAutomatic || get(handles.checkbox_preThreshold, 'value');
            
            if useAutomatic
            %     funParams.ThresholdValue = [ ];
                funParams.MethodIndx=get(handles.popupmenu_thresholdingMethod,'Value');
            
                if get(handles.checkbox_max, 'Value')
                    % If both checkbox are checked
                    maxJump=str2double(get(handles.edit_jump, 'String'));
                    if isnan(maxJump) || maxJump < 1
                        errordlg('Please provide a valid input for ''Maximum threshold jump''.','Setting Error','modal');
                        return;
                    end
                    funParams.MaxJump = str2double(get(handles.edit_jump,'String'));
                else
                    funParams.MaxJump = 0;
                end
            
                if get(handles.checkbox_exclude_outliers, 'Value')
                    excludeOutliersKSigma = str2double(get(handles.edit_exclude_outliers_k_sigma, 'String'));
                    if isnan(excludeOutliersKSigma) || excludeOutliersKSigma <= 0
                        errordlg('Please provide a valid input for ''Exclude Outliers Sigma''.','Setting Error','modal');
                        return;
                    end
                    funParams.ExcludeOutliers = excludeOutliersKSigma;
                else
                    funParams.ExcludeOutliers = 0;
                end
            
                if get(handles.checkbox_exclude_zero, 'Value')
                    funParams.ExcludeZero = true;
                else
                    funParams.ExcludeZero = false;
                end
            
                if useFixed
                    % Use the fixed thresholds below before automatic thresholding
                    funParams.PreThreshold = true;
                else
                    % Threshold value will be determined only automatically
                    funParams.PreThreshold = false;
                    funParams.ThresholdValue = [ ];
                end
            else
                % No automatic thresholding
                funParams.PreThreshold = false;
                % Since ~useAutomatic, useFixed is true and thus will be set below
                funParams.ThresholdValue = [ ];
                funParams.MaxJump = 0;
            end
            
            if useFixed
                threshold = get(handles.listbox_thresholdValues, 'String');
                if isempty(threshold)
                   errordlg('Please provide at least one threshold value.','Setting Error','modal')
                   return
                elseif length(threshold) ~= 1 && length(threshold) ~= length(channelIndex)
                   errordlg('Please provide the same number of threshold values as the input channels.','Setting Error','modal')
                   return
                else
                    [threshold,isPercentile] = stringToThresholds(app, threshold);
            %         threshold = str2double(threshold);
                    if any(isnan(threshold))
                        errordlg('Please provide valid threshold values.','Setting Error','modal')
                        return
                    end
                end
                funParams.ThresholdValue = threshold;
                funParams.IsPercentile = isPercentile;
            end
            
            
            % -------- Process Sanity check --------
            % ( only check underlying data )
            
            try
                userData.crtProc.sanityCheck;
            catch ME
            
                errordlg([ME.message 'Please double check your data.'],...
                            'Setting Error','modal');
                return;
            end
            
            processGUI_ApplyFcn(hObject, eventdata, handles,funParams);
        end

        % Button pushed function: pushbutton_select
        function pushbutton_select_Callback(app, event)
            % --- Executes on button press in pushbutton_select.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % call back function of 'select' button
            
            contents1 = get(handles.listbox_availableChannels, 'String');
            contents2 = get(handles.listbox_selectedChannels, 'String');
            id = get(handles.listbox_availableChannels, 'Value');
            
            % If channel has already been added, return;
            chanIndex1 = get(handles.listbox_availableChannels, 'Userdata');
            chanIndex2 = get(handles.listbox_selectedChannels, 'Userdata');
            [thresholdValues,isPercentile] = stringToThresholds(app, get(handles.listbox_thresholdValues,'String'));
            % thresholdValues = cellfun(@str2num,get(handles.listbox_thresholdValues,'String'));
            
            for i = id
                if any(strcmp(contents1{i}, contents2) )
                    continue;
                else
                    contents2{end+1} = contents1{i};
                    thresholdValues(end+1) = 0;
                    isPercentile(end+1) = false;
                    chanIndex2 = cat(2, chanIndex2, chanIndex1(i));
            
                end
            end
            
            set(handles.listbox_selectedChannels, 'String', contents2, 'Userdata', chanIndex2);
            set(handles.listbox_thresholdValues,'String',thresholdsToString(app, thresholdValues,isPercentile))
            update_data(hObject,eventdata,handles);
        end

        % Button pushed function: pushbutton_set_threshold
        function pushbutton_set_threshold_Callback(app, event)
            % --- Executes on button press in pushbutton_set_threshold.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            newThresholdValue = get(handles.slider_threshold,'Value');
            newIsPercentile = get(handles.checkbox_is_percentile,'Value');
            indx = get(handles.listbox_selectedChannels,'Value');
            % thresholdValues = cellfun(@str2num,get(handles.listbox_thresholdValues,'String'));
            [thresholdValues,isPercentile] = stringToThresholds(app, get(handles.listbox_thresholdValues,'String'));
            thresholdValues(indx) = newThresholdValue;
            isPercentile(indx) = newIsPercentile;
            set(handles.listbox_thresholdValues,'String',thresholdsToString(app, thresholdValues,isPercentile));
        end

        % Value changed function: edit_threshold, slider_threshold
        function threshold_edition(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            
            % Retrieve the valuye of the selected threshold
            if strcmp(get(hObject,'Tag'),'edit_threshold')
                thresholdValue = str2double(get(handles.edit_threshold, 'String'));
            else
                thresholdValue = get(handles.slider_threshold, 'Value');
            end
            thresholdValue=round(thresholdValue);
            
            % Check the validity of the supplied threshold
            if isnan(thresholdValue) || thresholdValue < get(handles.slider_threshold,'Min') || thresholdValue > get(handles.slider_threshold,'Max')
                warndlg('Please provide a valid coefficient.','Setting Error','modal');
                thresholdValue=userData.thresholdValue;
            end
            
            set(handles.slider_threshold,'Value',thresholdValue);
            set(handles.edit_threshold,'String',thresholdValue);
            
            % Save data and update graphics
            set(handles.figure1,'UserData',userData);
            guidata(hObject, handles);
            update_data(hObject,eventdata,handles);
        end

        % Value changed function: edit_GaussFilterSigma, 
        % ...and 3 other components
        function update_data(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %Hillary: if event is empty, call convertToGUIDE without event
            if isempty(event)
                [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app);
            else
                [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            end
            
            
            userData = get(handles.figure1, 'UserData');
            if isempty(userData), userData = struct(); end
            
            if strcmp(get(get(hObject,'Parent'),'Tag'),'uipanel_channels') ||...
                    strcmp(get(hObject,'Tag'),'listbox_thresholdValues')
                % Check if changes have been at the list box level
                linkedListBoxes = {'listbox_selectedChannels','listbox_thresholdValues'};
                checkLinkBox = strcmp(get(hObject,'Tag'),linkedListBoxes);
                if any(checkLinkBox)
                    value = get(handles.(linkedListBoxes{checkLinkBox}),'Value');
                    set(handles.(linkedListBoxes{~checkLinkBox}),'Value',value);
                else
                    value = get(handles.listbox_selectedChannels,'Value');
                end
                thresholdString = get(handles.listbox_thresholdValues,'String');
                if ~isempty(thresholdString)
            %         thresholdValue = str2double(thresholdString{value});
                    [thresholdValue,isPercentile] = stringToThresholds(app, thresholdString{value});
                else
                    thresholdValue=0;
                end
                set(handles.edit_threshold,'String',thresholdValue);
                set(handles.slider_threshold,'Value',thresholdValue);
                setPercentileMode(app, handles,isPercentile);
                if isempty(thresholdString),
                    if isfield(userData, 'previewFig'), delete(userData.previewFig); end
                    set(handles.figure1, 'UserData', userData);
                    guidata(hObject,handles);
                    return
                end
            end
            
            
            % Retrieve the channex index
            props=get(handles.listbox_selectedChannels,{'UserData','Value'});
            if isempty(props{1}), return; end
            chanIndx = props{1}(props{2});
            imIndx = get(handles.slider_frameNumber,'Value');
            thresholdValue = get(handles.slider_threshold, 'Value');
            
            % Load a new image in case the image number or channel has been changed
            if (all(chanIndx~=userData.chanIndx)) ||  (imIndx~=userData.imIndx)
                if ~isempty(userData.parentProc) && ~isempty(userData.crtPackage.processes_{userData.parentProc}) &&...
                        userData.crtPackage.processes_{userData.parentProc}.checkChannelOutput(chanIndx)
                    userData.imData=userData.crtPackage.processes_{userData.parentProc}.loadOutImage(chanIndx,imIndx);
                else
                    userData.imData=userData.MD.channels_(chanIndx).loadImage(imIndx);
                end
            
                % Get the value of the new maximum threshold
                if(get(handles.checkbox_is_percentile,'Value'))
                    maxThresholdValue = 100;
                    minThresholdValue = 0;
                else
                    maxThresholdValue=max(max(userData.imData(:)),1);
                    minThresholdValue=min(min(userData.imData(:)),0);
                end
                % Update the threshold Value if above the new maximum
                thresholdValue=min(thresholdValue,maxThresholdValue);
            
                set(handles.slider_threshold,'Value',thresholdValue,'Max',maxThresholdValue,...
                    'Min',minThresholdValue, ...
                    'SliderStep',[1/double(maxThresholdValue)  10/double(maxThresholdValue)]);
                set(handles.edit_threshold,'String',thresholdValue);
            
                userData.updateImage=1;
                userData.chanIndx=chanIndx;
                userData.imIndx=imIndx;
            else
                userData.updateImage=0;
            end
            
            
            % Save the data
            set(handles.figure1, 'UserData', userData);
            guidata(hObject,handles);
            
            % Update graphics if applicable
            if get(handles.checkbox_preview,'Value')
            
                % Create figure if non-existing or closed
                if ~ishandle(userData.previewFig)
                    userData.previewFig = figure('NumberTitle','off','Name','Thresholding preview',...
                        'Position',[50 50 userData.MD.imSize_(2) userData.MD.imSize_(1)]);
                    axes('Position',[0 0 1 1]);
                else
                    figure(userData.previewFig);
                end
            
                % Retrieve the handle of the figures image
                imHandle = findobj(userData.previewFig,'Type','image');
                if userData.updateImage || isempty(imHandle)
                    if isempty(imHandle)
                        imHandle=imagesc(userData.imData);
                        axis off;
                    else
                        set(imHandle,'CData',userData.imData);
                    end
                end
            
                % Preview the tresholding output using the alphaData mapping
                alphamask=ones(size(userData.imData));
                gaussFilterSigma = str2double(get(handles.edit_GaussFilterSigma, 'String'));
                if ~isnan(gaussFilterSigma) && gaussFilterSigma >0
                    imData = filterGauss2D(userData.imData,gaussFilterSigma);
                else
                    imData=userData.imData;
                end
            
            
                if get(handles.checkbox_auto,'Value')
                    methodIndx=get(handles.popupmenu_thresholdingMethod,'Value');
                    threshMethod = userData.crtProc.getMethods(methodIndx).func;
            
                    try
                        p.PreThreshold = get(handles.checkbox_preThreshold,'Value');
                        p.ExcludeZero = get(handles.checkbox_exclude_zero,'Value');
                        p.ExcludeOutliers = get(handles.checkbox_exclude_outliers,'Value') ...
                                          * str2double(get(handles.edit_exclude_outliers_k_sigma,'String'));
                        % iChan is not really 1, but we are reusing code from thresholdMovie
                        iChan = 1;
                        p.ThresholdValue = get(handles.slider_threshold, 'Value');
                        p.IsPercentile = get(handles.checkbox_is_percentile,'Value');
                        p.Invert = get(handles.checkbox_invert,'Value');
            
                        if(isnan(p.ExcludeOutliers))
                            p.ExcludeOutliers = 0;
                        end
            
                        data = imData;
            
                        %% Perform automatic thresholding
                        if(p.PreThreshold)
                            % Use automatic thresholding method only on pixels
                            % above fixed threshold
                            absoluteThreshold = p.ThresholdValue(iChan);
                            if(p.IsPercentile(iChan))
                                absoluteThreshold = prctile(data(:),absoluteThreshold);
                            end
                            data = data(data > absoluteThreshold);
                        end
                        if(p.ExcludeZero)
                            data = data(data ~= 0);
                        end
                        if(p.ExcludeOutliers)
                            % Exclude outliers before perforing automatic
                            % thresholding
                            [~,inliers] = detectOutliers(data,p.ExcludeOutliers);
                            data = data(inliers);
                        end
            
                        currThresh = threshMethod(data);
            
                        if(p.PreThreshold)
                            % Ensure absolute threshold is above fixed threshold
                            currThresh = max(currThresh,absoluteThreshold);
                        end
                        if(p.ExcludeZero)
                            % Threshold must be at least zero if excluding zeros
                            currThresh = max(currThresh,0);
                        end
            
                        %% Use automatic thresholding
            
                        if(p.Invert)
                            alphamask(imData>=currThresh)=.4;
                        else
                            alphamask(imData<=currThresh)=.4;
                        end
                    catch err
                        % To debug breakpoint here
                        rethrow(err);
                    end
                else
                    % Preview manual threshold
                    thresholdValue = get(handles.slider_threshold, 'Value');
                    if(get(handles.checkbox_is_percentile,'Value'))
                        thresholdValue = prctile(userData.imData(:),thresholdValue);
                    end
                    if(get(handles.checkbox_invert,'Value'))
                        alphamask(imData>=thresholdValue)=.4;
                    else
                        alphamask(imData<=thresholdValue)=.4;
                    end
                end
                set(imHandle,'AlphaData',alphamask,'AlphaDataMapping','none');


                set(handles.figure1, 'UserData', userData);
                guidata(hObject,handles);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Setting - Thresholding';
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.axes_help = uiaxes(app.figure1);
            app.axes_help.Tag = 'axes_help';
            app.axes_help.Visible = 'off';
            app.uipanel_channels = uibuttongroup(app.figure1);
            app.uipanel_channels.Title = 'Input Channels';
            app.uipanel_channels.Tag = 'uipanel_channels';
            app.checkbox_all = uicheckbox(app.uipanel_channels);
            app.checkbox_all.ValueChangedFcn = createCallbackFcn(app, @checkbox_all_Callback, true);
            app.checkbox_all.Tag = 'checkbox_all';
            app.checkbox_all.Text = ' Select all channels';
            app.text_body5 = uilabel(app.uipanel_channels);
            app.text_body5.Tag = 'text_body5';
            app.text_body5.Text = 'Selected Channels';
            app.text_body4 = uilabel(app.uipanel_channels);
            app.text_body4.Tag = 'text_body4';
            app.text_body4.Text = 'Available Input Channels';
            app.pushbutton_select = uibutton(app.uipanel_channels, 'push');
            app.pushbutton_select.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_select_Callback, true);
            app.pushbutton_select.Tag = 'pushbutton_select';
            app.pushbutton_select.Text = 'Select ->';
            app.pushbutton_delete = uibutton(app.uipanel_channels, 'push');
            app.pushbutton_delete.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_delete_Callback, true);
            app.pushbutton_delete.Tag = 'pushbutton_delete';
            app.pushbutton_delete.Text = 'Delete';
            app.listbox_selectedChannels = uilistbox(app.uipanel_channels);
            app.listbox_selectedChannels.Items = {'Item 1', 'Item 2'};
            app.listbox_selectedChannels.ValueChangedFcn = createCallbackFcn(app, @update_data, true);
            app.listbox_selectedChannels.Tag = 'listbox_selectedChannels';
            app.listbox_availableChannels = uilistbox(app.uipanel_channels);
            app.listbox_availableChannels.Items = {'Item 1', 'Item 2'};
            app.text_copyright = uilabel(app.figure1);
            app.text_copyright.Tag = 'text_copyright';
            app.text_copyright.Text = 'copyright info';
            app.text_processName = uilabel(app.figure1);
            app.text_processName.Tag = 'text_processName';
            app.text_processName.Text = 'Step x: ';
            app.pushbutton_cancel = uibutton(app.figure1, 'push');
            app.pushbutton_cancel.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_cancel_Callback, true);
            app.pushbutton_cancel.Tag = 'pushbutton_cancel';
            app.pushbutton_cancel.Text = 'Cancel';
            app.pushbutton_done = uibutton(app.figure1, 'push');
            app.pushbutton_done.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_done_Callback, true);
            app.pushbutton_done.Tag = 'pushbutton_done';
            app.pushbutton_done.Text = 'Apply';
            app.checkbox_applytoall = uicheckbox(app.figure1);
            app.checkbox_applytoall.Tag = 'checkbox_applytoall';
            app.checkbox_applytoall.Text = 'Apply settings to all movies';
            app.checkbox_applytoall.Value = true;
            app.uipanel_1 = uibuttongroup(app.figure1);
            app.uipanel_1.Title = 'Thresholding';
            app.uipanel_1.Tag = 'uipanel_1';
            app.uipanel_automaticThresholding = uipanel(app.uipanel_1);
            app.uipanel_automaticThresholding.Title = 'Automatic thresholding';
            app.uipanel_automaticThresholding.Tag = 'uipanel_automaticThresholding';
            app.edit_jump = uieditfield(app.uipanel_automaticThresholding, 'text');
            app.edit_jump.Tag = 'edit_jump';
            app.edit_jump.Enable = 'off';
            app.checkbox_max = uicheckbox(app.uipanel_automaticThresholding);
            app.checkbox_max.ValueChangedFcn = createCallbackFcn(app, @checkbox_max_Callback, true);
            app.checkbox_max.Tag = 'checkbox_max';
            app.checkbox_max.Text = ' Maximum threshold jump:';
            app.popupmenu_thresholdingMethod = uidropdown(app.uipanel_automaticThresholding);
            app.popupmenu_thresholdingMethod.Items = {'Methods list'};
            app.popupmenu_thresholdingMethod.ValueChangedFcn = createCallbackFcn(app, @update_data, true);
            app.popupmenu_thresholdingMethod.Tag = 'popupmenu_thresholdingMethod';
            app.popupmenu_thresholdingMethod.Value = 'Methods list';
            app.text_thresholdingMethod = uilabel(app.uipanel_automaticThresholding);
            app.text_thresholdingMethod.Tag = 'text_thresholdingMethod';
            app.text_thresholdingMethod.Text = 'Thresholding method';
            app.checkbox_preThreshold = uicheckbox(app.uipanel_automaticThresholding);
            app.checkbox_preThreshold.ValueChangedFcn = createCallbackFcn(app, @checkbox_preThreshold_Callback, true);
            app.checkbox_preThreshold.Tag = 'checkbox_preThreshold';
            app.checkbox_preThreshold.Text = ' Apply a fixed threshold from below first';
            app.checkbox_exclude_outliers = uicheckbox(app.uipanel_automaticThresholding);
            app.checkbox_exclude_outliers.ValueChangedFcn = createCallbackFcn(app, @checkbox_exclude_outliers_Callback, true);
            app.checkbox_exclude_outliers.Tag = 'checkbox_exclude_outliers';
            app.checkbox_exclude_outliers.Text = ' Exclude outliers that are more than ';
            app.edit_exclude_outliers_k_sigma = uieditfield(app.uipanel_automaticThresholding, 'text');
            app.edit_exclude_outliers_k_sigma.ValueChangedFcn = createCallbackFcn(app, @edit_exclude_outliers_k_sigma_Callback, true);
            app.edit_exclude_outliers_k_sigma.Tag = 'edit_exclude_outliers_k_sigma';
            app.edit_exclude_outliers_k_sigma.Enable = 'off';
            app.edit_exclude_outliers_k_sigma.Value = '3';
            app.text18 = uilabel(app.uipanel_automaticThresholding);
            app.text18.Tag = 'text18';
            app.text18.Text = 'sigma away from mean';
            app.checkbox_exclude_zero = uicheckbox(app.uipanel_automaticThresholding);
            app.checkbox_exclude_zero.Tag = 'checkbox_exclude_zero';
            app.checkbox_exclude_zero.Text = ' Exclude zeros';
            app.uipanel_fixedThreshold = uipanel(app.uipanel_1);
            app.uipanel_fixedThreshold.Title = 'Fixed Threshold';
            app.uipanel_fixedThreshold.Tag = 'uipanel_fixedThreshold';
            app.edit_threshold = uieditfield(app.uipanel_fixedThreshold, 'text');
            app.edit_threshold.ValueChangedFcn = createCallbackFcn(app, @threshold_edition, true);
            app.edit_threshold.Tag = 'edit_threshold';
            app.edit_threshold.Enable = 'off';
            app.edit_threshold.Value = '0';
            app.slider_threshold = uislider(app.uipanel_fixedThreshold);
            app.slider_threshold.Limits = [0 1];
            app.slider_threshold.MajorTicks = [];
            app.slider_threshold.ValueChangedFcn = createCallbackFcn(app, @threshold_edition, true);
            app.slider_threshold.MinorTicks = [];
            app.slider_threshold.Tag = 'slider_threshold';
            app.listbox_thresholdValues = uilistbox(app.uipanel_fixedThreshold);
            app.listbox_thresholdValues.Items = {};
            app.listbox_thresholdValues.ValueChangedFcn = createCallbackFcn(app, @update_data, true);
            app.listbox_thresholdValues.Tag = 'listbox_thresholdValues';
            app.listbox_thresholdValues.Enable = 'off';
            app.listbox_thresholdValues.Tooltip = 'List of thresholds per channel for the selected channels above. Those with ''%'' represent percentile thresholds.';
            app.listbox_thresholdValues.Value = {};
            app.pushbutton_set_threshold = uibutton(app.uipanel_fixedThreshold, 'push');
            app.pushbutton_set_threshold.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_set_threshold_Callback, true);
            app.pushbutton_set_threshold.Tag = 'pushbutton_set_threshold';
            app.pushbutton_set_threshold.Enable = 'off';
            app.pushbutton_set_threshold.Text = 'Set ->';
            app.text_thresholdValue = uilabel(app.uipanel_fixedThreshold);
            app.text_thresholdValue.Tag = 'text_thresholdValue';
            app.text_thresholdValue.Text = 'Threshold value';
            app.checkbox_is_percentile = uicheckbox(app.uipanel_fixedThreshold);
            app.checkbox_is_percentile.ValueChangedFcn = createCallbackFcn(app, @checkbox_is_percentile_Callback, true);
            app.checkbox_is_percentile.Tag = 'checkbox_is_percentile';
            app.checkbox_is_percentile.Tooltip = 'When checked, the threshold represents a percentile.When unchecked, threshold represents an absolute intensity value. On change, this will convert between percentiles and absolute intensities from the current frame.';
            app.checkbox_is_percentile.Text = 'Percentile';
            app.text_percentile = uilabel(app.uipanel_fixedThreshold);
            app.text_percentile.Tag = 'text_percentile';
            app.text_percentile.Text = '';
            app.checkbox_auto = uicheckbox(app.uipanel_1);
            app.checkbox_auto.ValueChangedFcn = createCallbackFcn(app, @checkbox_auto_Callback, true);
            app.checkbox_auto.Tag = 'checkbox_auto';
            app.checkbox_auto.Text = ' Use automatic thresholding';
            app.checkbox_auto.Value = true;
            app.checkbox_preview = uicheckbox(app.uipanel_1);
            app.checkbox_preview.ValueChangedFcn = createCallbackFcn(app, @checkbox_preview_Callback, true);
            app.checkbox_preview.Tag = 'checkbox_preview';
            app.checkbox_preview.Text = ' Preview thresholded image';
            app.edit_frameNumber = uieditfield(app.uipanel_1, 'text');
            app.edit_frameNumber.ValueChangedFcn = createCallbackFcn(app, @imageNumber_edition, true);
            app.edit_frameNumber.Tag = 'edit_frameNumber';
            app.edit_frameNumber.Value = '1';
            app.slider_frameNumber = uislider(app.uipanel_1);
            app.slider_frameNumber.Limits = [0 1];
            app.slider_frameNumber.MajorTicks = [];
            app.slider_frameNumber.ValueChangedFcn = createCallbackFcn(app, @imageNumber_edition, true);
            app.slider_frameNumber.MinorTicks = [];
            app.slider_frameNumber.Tag = 'slider_frameNumber';
            app.slider_frameNumber.Value = 1;
            app.text11 = uilabel(app.uipanel_1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Frame:';
            app.text_nFrames = uilabel(app.uipanel_1);
            app.text_nFrames.Tag = 'text_nFrames';
            app.text_nFrames.Text = '/10 ';
            app.text_GaussFilterSigma = uilabel(app.uipanel_1);
            app.text_GaussFilterSigma.Tag = 'text_GaussFilterSigma';
            app.text_GaussFilterSigma.Text = 'Standard deviation of Gaussian filtering';
            app.edit_GaussFilterSigma = uieditfield(app.uipanel_1, 'text');
            app.edit_GaussFilterSigma.ValueChangedFcn = createCallbackFcn(app, @update_data, true);
            app.edit_GaussFilterSigma.Tag = 'edit_GaussFilterSigma';
            app.text16 = uilabel(app.uipanel_1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'pixels';
            app.checkbox_invert = uicheckbox(app.uipanel_1);
            app.checkbox_invert.ValueChangedFcn = createCallbackFcn(app, @checkbox_invert_Callback, true);
            app.checkbox_invert.Tag = 'checkbox_invert';
            app.checkbox_invert.Text = 'Select pixels less than threshold';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thresholdProcessGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)thresholdProcessGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end